classdef GraphicUserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SphericalFloatExoskeletonLabel  matlab.ui.control.Label
        Image14                         matlab.ui.control.Image
        Image13                         matlab.ui.control.Image
        TargetSelector                  slrealtime.ui.control.TargetSelector
        SimulationTimeEditField         slrealtime.ui.control.SimulationTimeEditField
        SystemLog                       slrealtime.ui.control.SystemLog
        StartStopButton                 slrealtime.ui.control.StartStopButton
        LoadButton                      slrealtime.ui.control.LoadButton
        ConnectButton                   slrealtime.ui.control.ConnectButton
        TabGroup2                       matlab.ui.container.TabGroup
        STARTUPTab                      matlab.ui.container.Tab
        TorqueMonitorLabel              matlab.ui.control.Label
        HomingMonitorLabel              matlab.ui.control.Label
        J6_HomingLamp                   matlab.ui.control.Lamp
        J5_HPLamp_2Label_2              matlab.ui.control.Label
        AksimPosM6OUTEditField          matlab.ui.control.EditField
        AksimPosM6OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM6INEditField           matlab.ui.control.EditField
        AksimPosM6INEditFieldLabel      matlab.ui.control.Label
        ActualPosM6EditField            matlab.ui.control.EditField
        ActualPosM6EditFieldLabel       matlab.ui.control.Label
        J5_HomingLamp                   matlab.ui.control.Lamp
        J5_HomingLampLabel              matlab.ui.control.Label
        J4_HomingLamp                   matlab.ui.control.Lamp
        J4_HomingLampLabel              matlab.ui.control.Label
        J3_HomingLamp                   matlab.ui.control.Lamp
        J3_HomingLampLabel              matlab.ui.control.Label
        J2_HomingLamp                   matlab.ui.control.Lamp
        J2_HomingLampLabel              matlab.ui.control.Label
        J1_HomingLamp                   matlab.ui.control.Lamp
        J1_HomingLampLabel              matlab.ui.control.Label
        WakeUpFLOATEvoSwitch            matlab.ui.control.ToggleSwitch
        WakeUpFLOATEvoSwitchLabel       matlab.ui.control.Label
        AksimPosM5OUTEditField          matlab.ui.control.EditField
        AksimPosM5OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM4OUTEditField          matlab.ui.control.EditField
        AksimPosM4OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM3OUTEditField          matlab.ui.control.EditField
        AksimPosM3OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM2OUTEditField          matlab.ui.control.EditField
        AksimPosM2OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM1OUTEditField          matlab.ui.control.EditField
        AksimPosM1OUTEditFieldLabel     matlab.ui.control.Label
        AksimPosM5INEditField           matlab.ui.control.EditField
        AksimPosM5INEditFieldLabel      matlab.ui.control.Label
        AksimPosM4INEditField           matlab.ui.control.EditField
        AksimPosM4INEditFieldLabel      matlab.ui.control.Label
        AksimPosM3INEditField           matlab.ui.control.EditField
        AksimPosM3INEditFieldLabel      matlab.ui.control.Label
        AksimPosM2INEditField           matlab.ui.control.EditField
        AksimPosM2INEditFieldLabel      matlab.ui.control.Label
        AksimPosM1INEditField           matlab.ui.control.EditField
        AksimPosM1INEditFieldLabel      matlab.ui.control.Label
        ActualPosM5EditField            matlab.ui.control.EditField
        ActualPosM5EditFieldLabel       matlab.ui.control.Label
        ActualPosM4EditField            matlab.ui.control.EditField
        ActualPosM4EditFieldLabel       matlab.ui.control.Label
        ActualPosM3EditField            matlab.ui.control.EditField
        ActualPosM3EditFieldLabel       matlab.ui.control.Label
        ActualPosM2EditField            matlab.ui.control.EditField
        ActualPosM2EditFieldLabel       matlab.ui.control.Label
        ActualPosM1EditField            matlab.ui.control.EditField
        ActualPosM1EditFieldLabel       matlab.ui.control.Label
        UIAxes_T6                       matlab.ui.control.UIAxes
        UIAxes_T5                       matlab.ui.control.UIAxes
        UIAxes_T4                       matlab.ui.control.UIAxes
        UIAxes_T3                       matlab.ui.control.UIAxes
        UIAxes_T2                       matlab.ui.control.UIAxes
        UIAxes_T1                       matlab.ui.control.UIAxes
        WELCOMETab                      matlab.ui.container.Tab
        MOVEMENTDIRECTIONLabel          matlab.ui.control.Label
        Image20                         matlab.ui.control.Image
        TargetSpinner                   matlab.ui.control.Spinner
        TargetSpinnerLabel              matlab.ui.control.Label
        TimingsecSpinner                matlab.ui.control.Spinner
        TimingsecSpinnerLabel           matlab.ui.control.Label
        RepetitionsSpinner              matlab.ui.control.Spinner
        RepetitionsSpinnerLabel         matlab.ui.control.Label
        YAWEditField                    matlab.ui.control.EditField
        YAWEditFieldLabel               matlab.ui.control.Label
        PITCHEditField                  matlab.ui.control.EditField
        PITCHEditFieldLabel             matlab.ui.control.Label
        ROLLEditField                   matlab.ui.control.EditField
        ROLLEditFieldLabel              matlab.ui.control.Label
        ScapularElevationEditField      matlab.ui.control.NumericEditField
        ScapularElevationEditFieldLabel  matlab.ui.control.Label
        ElbowFlexionEditField           matlab.ui.control.NumericEditField
        ElbowFlexionEditFieldLabel      matlab.ui.control.Label
        ShoulderAbductionEditField      matlab.ui.control.NumericEditField
        ShoulderAbductionEditFieldLabel  matlab.ui.control.Label
        ScapularProtractionEditField    matlab.ui.control.NumericEditField
        ScapularProtractionEditFieldLabel  matlab.ui.control.Label
        ShoulderRorationEditField       matlab.ui.control.NumericEditField
        ShoulderRorationEditFieldLabel  matlab.ui.control.Label
        ShoulderFlexionEditField        matlab.ui.control.NumericEditField
        ShoulderFlexionEditFieldLabel   matlab.ui.control.Label
        Image17                         matlab.ui.control.Image
        Image16                         matlab.ui.control.Image
        LearnReplayLabel                matlab.ui.control.Label
        Image15                         matlab.ui.control.Image
        ShoulderAbductionAdductionLabel  matlab.ui.control.Label
        Image12                         matlab.ui.control.Image
        ElbowFlexionExtensionLabel_2    matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        ShoulderFlexionExtensionLabel   matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        StatusLabel                     matlab.ui.control.Label
        StatusImage                     matlab.ui.control.Image
        Image8_2                        matlab.ui.control.Image
        RPYANGLESLabel                  matlab.ui.control.Label
        Image7_3                        matlab.ui.control.Image
        CompliantexercisesLabel         matlab.ui.control.Label
        TransparencyLabel               matlab.ui.control.Label
        Image11                         matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        JOINTANGLELabel                 matlab.ui.control.Label
        Image7_2                        matlab.ui.control.Image
        WelcometoSphericalFloatAPPLabel  matlab.ui.control.Label
        GoHomeLabel                     matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        ASSISTANCELEVELButtonGroup      matlab.ui.container.ButtonGroup
        Image19                         matlab.ui.control.Image
        Image18                         matlab.ui.control.Image
        NoneButton                      matlab.ui.control.RadioButton
        LowButton_2                     matlab.ui.control.RadioButton
        MediumButton                    matlab.ui.control.RadioButton
        HighButton                      matlab.ui.control.RadioButton
        UTILITYTab                      matlab.ui.container.Tab
        ModeofOperationm6EditField      matlab.ui.control.EditField
        ModeofOperationm6EditFieldLabel  matlab.ui.control.Label
        Errorcode_m6EditField           matlab.ui.control.EditField
        Errorcode_m6EditFieldLabel      matlab.ui.control.Label
        ReadNMTstate_m6EditField        matlab.ui.control.EditField
        ReadNMTstate_m6EditFieldLabel   matlab.ui.control.Label
        Statusword_m6EditField          matlab.ui.control.EditField
        Statusword_m6EditFieldLabel     matlab.ui.control.Label
        setModem6ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton_6  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton_6  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton_6  matlab.ui.control.RadioButton
        HomingModeHMMButton_6           matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton_6  matlab.ui.control.RadioButton
        ProfilePositionModePPMButton_6  matlab.ui.control.RadioButton
        controlwordm6ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton_6                    matlab.ui.control.RadioButton
        FaultresetButton_6              matlab.ui.control.RadioButton
        EnableOperationButton_6         matlab.ui.control.RadioButton
        DisableOperationButton_6        matlab.ui.control.RadioButton
        QuickstopButton_6               matlab.ui.control.RadioButton
        DisableVoltageButton_6          matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton_6  matlab.ui.control.RadioButton
        SwitchOnButton_6                matlab.ui.control.RadioButton
        ShutdownButton_6                matlab.ui.control.RadioButton
        setNMTstatebothmotorsButtonGroup  matlab.ui.container.ButtonGroup
        StopRemoteNodeButton            matlab.ui.control.RadioButton
        StartRemoteNodeButton           matlab.ui.control.RadioButton
        ResetNodeButton                 matlab.ui.control.RadioButton
        ResetCommunicationButton        matlab.ui.control.RadioButton
        EnterPreOperationalButton       matlab.ui.control.RadioButton
        ModeofOperationm5EditField      matlab.ui.control.EditField
        ModeofOperationm5EditFieldLabel  matlab.ui.control.Label
        Errorcode_m5EditField           matlab.ui.control.EditField
        Errorcode_m5EditFieldLabel      matlab.ui.control.Label
        ReadNMTstate_m5EditField        matlab.ui.control.EditField
        ReadNMTstate_m5EditFieldLabel   matlab.ui.control.Label
        Statusword_m5EditField          matlab.ui.control.EditField
        Statusword_m5EditFieldLabel     matlab.ui.control.Label
        ModeofOperationm4EditField      matlab.ui.control.EditField
        ModeofOperationm4EditFieldLabel  matlab.ui.control.Label
        Errorcode_m4EditField           matlab.ui.control.EditField
        Errorcode_m4EditFieldLabel      matlab.ui.control.Label
        ReadNMTstate_m4EditField        matlab.ui.control.EditField
        ReadNMTstate_m4EditFieldLabel   matlab.ui.control.Label
        Statusword_m4EditField          matlab.ui.control.EditField
        Statusword_m4EditFieldLabel     matlab.ui.control.Label
        setModem5ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton_5  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton_5  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton_5  matlab.ui.control.RadioButton
        HomingModeHMMButton_5           matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton_5  matlab.ui.control.RadioButton
        ProfilePositionModePPMButton_5  matlab.ui.control.RadioButton
        setModem4ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton_4  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton_4  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton_4  matlab.ui.control.RadioButton
        HomingModeHMMButton_4           matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton_4  matlab.ui.control.RadioButton
        ProfilePositionModePPMButton_4  matlab.ui.control.RadioButton
        controlwordm5ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton_5                    matlab.ui.control.RadioButton
        FaultresetButton_5              matlab.ui.control.RadioButton
        EnableOperationButton_5         matlab.ui.control.RadioButton
        DisableOperationButton_5        matlab.ui.control.RadioButton
        QuickstopButton_5               matlab.ui.control.RadioButton
        DisableVoltageButton_5          matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton_5  matlab.ui.control.RadioButton
        SwitchOnButton_5                matlab.ui.control.RadioButton
        ShutdownButton_5                matlab.ui.control.RadioButton
        controlwordm4ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton_4                    matlab.ui.control.RadioButton
        FaultresetButton_4              matlab.ui.control.RadioButton
        EnableOperationButton_4         matlab.ui.control.RadioButton
        DisableOperationButton_4        matlab.ui.control.RadioButton
        QuickstopButton_4               matlab.ui.control.RadioButton
        DisableVoltageButton_4          matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton_4  matlab.ui.control.RadioButton
        SwitchOnButton_4                matlab.ui.control.RadioButton
        ShutdownButton_4                matlab.ui.control.RadioButton
        controlwordm3ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton_3                    matlab.ui.control.RadioButton
        FaultresetButton_3              matlab.ui.control.RadioButton
        EnableOperationButton_3         matlab.ui.control.RadioButton
        DisableOperationButton_3        matlab.ui.control.RadioButton
        QuickstopButton_3               matlab.ui.control.RadioButton
        DisableVoltageButton_3          matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton_3  matlab.ui.control.RadioButton
        SwitchOnButton_3                matlab.ui.control.RadioButton
        ShutdownButton_3                matlab.ui.control.RadioButton
        controlwordm2ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton_2                    matlab.ui.control.RadioButton
        FaultresetButton_2              matlab.ui.control.RadioButton
        EnableOperationButton_2         matlab.ui.control.RadioButton
        DisableOperationButton_2        matlab.ui.control.RadioButton
        QuickstopButton_2               matlab.ui.control.RadioButton
        DisableVoltageButton_2          matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton_2  matlab.ui.control.RadioButton
        SwitchOnButton_2                matlab.ui.control.RadioButton
        ShutdownButton_2                matlab.ui.control.RadioButton
        controlwordm1ButtonGroup        matlab.ui.container.ButtonGroup
        HaltButton                      matlab.ui.control.RadioButton
        FaultresetButton                matlab.ui.control.RadioButton
        EnableOperationButton           matlab.ui.control.RadioButton
        DisableOperationButton          matlab.ui.control.RadioButton
        QuickstopButton                 matlab.ui.control.RadioButton
        DisableVoltageButton            matlab.ui.control.RadioButton
        SwitchOnEnableOperationButton   matlab.ui.control.RadioButton
        SwitchOnButton                  matlab.ui.control.RadioButton
        ShutdownButton                  matlab.ui.control.RadioButton
        setModem3ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton_3  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton_3  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton_3  matlab.ui.control.RadioButton
        HomingModeHMMButton_3           matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton_3  matlab.ui.control.RadioButton
        ProfilePositionModePPMButton_3  matlab.ui.control.RadioButton
        setModem2ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton_2  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton_2  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton_2  matlab.ui.control.RadioButton
        HomingModeHMMButton_2           matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton_2  matlab.ui.control.RadioButton
        ProfilePositionModePPMButton_2  matlab.ui.control.RadioButton
        setModem1ButtonGroup            matlab.ui.container.ButtonGroup
        CyclicSynchronousTorqueModeCSTButton  matlab.ui.control.RadioButton
        CyclicSynchronousVelocityModeCSVButton  matlab.ui.control.RadioButton
        CyclicSynchronousPositionModeCSPButton  matlab.ui.control.RadioButton
        HomingModeHMMButton             matlab.ui.control.RadioButton
        ProfileVelocityModePVMButton    matlab.ui.control.RadioButton
        ProfilePositionModePPMButton    matlab.ui.control.RadioButton
        ModeofOperationm3EditField      matlab.ui.control.EditField
        ModeofOperationm3EditFieldLabel  matlab.ui.control.Label
        Errorcode_m3EditField           matlab.ui.control.EditField
        Errorcode_m3EditFieldLabel      matlab.ui.control.Label
        ReadNMTstate_m3EditField        matlab.ui.control.EditField
        ReadNMTstate_m3EditFieldLabel   matlab.ui.control.Label
        Statusword_m3EditField          matlab.ui.control.EditField
        Statusword_m3EditFieldLabel     matlab.ui.control.Label
        ModeofOperationm2EditField      matlab.ui.control.EditField
        ModeofOperationm2EditFieldLabel  matlab.ui.control.Label
        Errorcode_m2EditField           matlab.ui.control.EditField
        Errorcode_m2EditFieldLabel      matlab.ui.control.Label
        ReadNMTstate_m2EditField        matlab.ui.control.EditField
        ReadNMTstate_m2EditFieldLabel   matlab.ui.control.Label
        Statusword_m2EditField          matlab.ui.control.EditField
        Statusword_m2EditFieldLabel     matlab.ui.control.Label
        ModeofOperationm1EditField      matlab.ui.control.EditField
        ModeofOperationm1EditFieldLabel  matlab.ui.control.Label
        ReadNMTstate_m1EditField        matlab.ui.control.EditField
        ReadNMTstate_m1EditFieldLabel   matlab.ui.control.Label
        Errorcode_m1EditField           matlab.ui.control.EditField
        Errorcode_m1EditFieldLabel      matlab.ui.control.Label
        Statusword_m1EditField          matlab.ui.control.EditField
        Statusword_m1EditFieldLabel     matlab.ui.control.Label
    end


    properties (Access = private)
        tg % Target Object
        SLRTApp = 'ExoskeletonControlModel'   % Name of the Simulink model

        errorcode % errocode decoding from file

        Instrument
        InstrumentManager
    end
    properties (Access = public)
        % VARIABLES USED TO REAL TIME MODIFY THE DAMPING AND STIFFNESS
        % COEFFICIENTS FOR THE VISCOUS-ELASTIC ADMITTANCE CONTROL
        damper1=0;
        damper2=0;
        damper3=0;
        damper4=0;
        damper5=0;
        damper6=0;
        k1=0;
        k2=0;
        k3=0;
        k4=0;
        k5=0;
        k6=0;
        MY_MODE_FLAG=0;
        % FLAG USED TO DISCRIMINATE BETWEEN ADMITTANCE SINGLE JOINT CONTROL
        % AND VELOCITY FIELD CONTROL
        flag_control=0;
        flag_adm=0;
        flag_gravity=0;
        flag_compliant_ex=0;
        my_gravity=0;

        beta_imp=0;
        k_imp=0;
    end
    methods (Access = private)


        function get_status_error_nmt(app, instObj, eventData)
            if ~isempty(eventData.AcquireGroupData.Time)
                %% status
                [~, statusword_1]   = instObj.getCallbackDataForSignal(eventData, 'statusword_1','Decimation',100);
                statusword_1=statusword_1(end);

                [~, statusword_2]   = instObj.getCallbackDataForSignal(eventData, 'statusword_2','Decimation',100);
                statusword_2=statusword_2(end);

                [~, statusword_3]   = instObj.getCallbackDataForSignal(eventData, 'statusword_3','Decimation',100);
                statusword_3=statusword_3(end);

                [~, statusword_4]   = instObj.getCallbackDataForSignal(eventData, 'statusword_4','Decimation',100);
                statusword_4=statusword_4(end);

                [~, statusword_5]   = instObj.getCallbackDataForSignal(eventData, 'statusword_5','Decimation',100);
                statusword_5=statusword_5(end);

                [~, statusword_6]   = instObj.getCallbackDataForSignal(eventData, 'statusword_6','Decimation',100);
                statusword_6=statusword_6(end);

                app.Statusword_m1EditField.Value = app.get_statusword(statusword_1);
                app.Statusword_m2EditField.Value = app.get_statusword(statusword_2);
                app.Statusword_m3EditField.Value = app.get_statusword(statusword_3);
                app.Statusword_m4EditField.Value = app.get_statusword(statusword_4);
                app.Statusword_m5EditField.Value = app.get_statusword(statusword_5);
                app.Statusword_m6EditField.Value = app.get_statusword(statusword_6);

                %% error
                [~, error_code_m1]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m1','Decimation',100);
                error_code_m1=error_code_m1(end);

                [~, error_code_m2]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m2','Decimation',100);
                error_code_m2=error_code_m2(end);

                [~, error_code_m3]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m3','Decimation',100);
                error_code_m3=error_code_m3(end);

                [~, error_code_m4]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m4','Decimation',100);
                error_code_m4=error_code_m4(end);

                [~, error_code_m5]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m5','Decimation',100);
                error_code_m5=error_code_m5(end);
                [~, error_code_m6]   = instObj.getCallbackDataForSignal(eventData, 'error_code_m6','Decimation',100);
                error_code_m6=error_code_m6(end);

                app.Errorcode_m1EditField.Value = app.get_error(error_code_m1);
                app.Errorcode_m2EditField.Value = app.get_error(error_code_m2);
                app.Errorcode_m3EditField.Value = app.get_error(error_code_m3);
                app.Errorcode_m4EditField.Value = app.get_error(error_code_m4);
                app.Errorcode_m5EditField.Value = app.get_error(error_code_m5);
                app.Errorcode_m6EditField.Value = app.get_error(error_code_m6);
                %% NMT
                [~, nmt_state_1]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_1','Decimation',100);
                nmt_state_1=nmt_state_1(end);
                [~, nmt_state_2]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_2','Decimation',100);
                nmt_state_2=nmt_state_2(end);
                [~, nmt_state_3]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_3','Decimation',100);
                nmt_state_3=nmt_state_3(end);
                [~, nmt_state_4]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_4','Decimation',100);
                nmt_state_4=nmt_state_4(end);
                [~, nmt_state_5]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_5','Decimation',100);
                nmt_state_5=nmt_state_5(end);
                [~, nmt_state_6]   = instObj.getCallbackDataForSignal(eventData, 'NMT_state_6','Decimation',100);
                nmt_state_6=nmt_state_6(end);

                app.ReadNMTstate_m1EditField.Value = app.get_NMT_state(nmt_state_1);
                app.ReadNMTstate_m2EditField.Value = app.get_NMT_state(nmt_state_2);
                app.ReadNMTstate_m3EditField.Value = app.get_NMT_state(nmt_state_3);
                app.ReadNMTstate_m4EditField.Value = app.get_NMT_state(nmt_state_4);
                app.ReadNMTstate_m5EditField.Value = app.get_NMT_state(nmt_state_5);
                app.ReadNMTstate_m6EditField.Value = app.get_NMT_state(nmt_state_6);

                %% Mode
                [~, mode_1]   = instObj.getCallbackDataForSignal(eventData, 'mode_1','Decimation',100);
                mode_1=mode_1(end);
                [~, mode_2]   = instObj.getCallbackDataForSignal(eventData, 'mode_2','Decimation',100);
                mode_2=mode_2(end);
                [~, mode_3]   = instObj.getCallbackDataForSignal(eventData, 'mode_3','Decimation',100);
                mode_3=mode_3(end);
                [~, mode_4]   = instObj.getCallbackDataForSignal(eventData, 'mode_4','Decimation',100);
                mode_4=mode_4(end);
                [~, mode_5]   = instObj.getCallbackDataForSignal(eventData, 'mode_5','Decimation',100);
                mode_5=mode_5(end);

                [~, mode_6]   = instObj.getCallbackDataForSignal(eventData, 'mode_6','Decimation',100);
                mode_6=mode_6(end);

                app.ModeofOperationm1EditField.Value = app.get_mode(mode_1);
                app.ModeofOperationm2EditField.Value = app.get_mode(mode_2);
                app.ModeofOperationm3EditField.Value = app.get_mode(mode_3);
                app.ModeofOperationm4EditField.Value = app.get_mode(mode_4);
                app.ModeofOperationm5EditField.Value = app.get_mode(mode_5);
                app.ModeofOperationm6EditField.Value = app.get_mode(mode_6);


                %% Motor pos
                [~, pos_M1_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_1','Decimation',100);
                [~, pos_M2_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_2','Decimation',100);
                [~, pos_M3_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_3','Decimation',100);
                [~, pos_M4_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_4','Decimation',100);
                [~, pos_M5_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_5','Decimation',100);
                [~, pos_M6_ticks]   = instObj.getCallbackDataForSignal(eventData, 'pos_actual_6','Decimation',100);

                app.ActualPosM1EditField.Value = string(pos_M1_ticks(end));
                app.ActualPosM2EditField.Value = string(pos_M2_ticks(end));
                app.ActualPosM3EditField.Value = string(pos_M3_ticks(end));
                app.ActualPosM4EditField.Value = string(pos_M4_ticks(end));
                app.ActualPosM5EditField.Value = string(pos_M5_ticks(end));
                app.ActualPosM6EditField.Value = string(pos_M6_ticks(end));

                %% Aksim In
                [~, M1_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M1_AKSIM1_TICKS','Decimation',100);
                [~, M2_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M2_AKSIM1_TICKS','Decimation',100);
                [~, M3_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M3_AKSIM1_TICKS','Decimation',100);
                [~, M4_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M4_AKSIM1_TICKS','Decimation',100);
                [~, M5_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M5_AKSIM1_TICKS','Decimation',100);
                [~, M6_AKSIM_1]   = instObj.getCallbackDataForSignal(eventData, 'M6_AKSIM1_TICKS','Decimation',100);
                app.AksimPosM1INEditField.Value = string(M1_AKSIM_1(end));
                app.AksimPosM2INEditField.Value = string(M2_AKSIM_1(end));
                app.AksimPosM3INEditField.Value = string(M3_AKSIM_1(end));
                app.AksimPosM4INEditField.Value = string(M4_AKSIM_1(end));
                app.AksimPosM5INEditField.Value = string(M5_AKSIM_1(end));
                app.AksimPosM6INEditField.Value = string(M6_AKSIM_1(end));
                %% Aksim Out
                [~, M1_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M1_AKSIM2_TICKS','Decimation',100);
                [~, M2_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M2_AKSIM2_TICKS','Decimation',100);
                [~, M3_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M3_AKSIM2_TICKS','Decimation',100);
                [~, M4_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M4_AKSIM2_TICKS','Decimation',100);
                [~, M5_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M5_AKSIM2_TICKS','Decimation',100);
                [~, M6_AKSIM_2]   = instObj.getCallbackDataForSignal(eventData, 'M6_AKSIM2_TICKS','Decimation',100);
                app.AksimPosM1OUTEditField.Value = string(M1_AKSIM_2(end));
                app.AksimPosM2OUTEditField.Value = string(M2_AKSIM_2(end));
                app.AksimPosM3OUTEditField.Value = string(M3_AKSIM_2(end));
                app.AksimPosM4OUTEditField.Value = string(M4_AKSIM_2(end));
                app.AksimPosM5OUTEditField.Value = string(M5_AKSIM_2(end));
                app.AksimPosM6OUTEditField.Value = string(M6_AKSIM_2(end));


                %% JOINTS ANGLES
                [~, j1_deg]   = instObj.getCallbackDataForSignal(eventData, 'j1_deg','Decimation',100);
                [~, j2_deg]   = instObj.getCallbackDataForSignal(eventData, 'j2_deg','Decimation',100);
                [~, j3_deg]   = instObj.getCallbackDataForSignal(eventData, 'j3_deg','Decimation',100);
                [~, j4_deg]   = instObj.getCallbackDataForSignal(eventData, 'j4_deg','Decimation',100);
                [~, j5_deg]   = instObj.getCallbackDataForSignal(eventData, 'j5_deg','Decimation',100);
                [~, j6_deg]   = instObj.getCallbackDataForSignal(eventData, 'j6_deg','Decimation',100);
                app.ScapularProtractionEditField.Value=(j1_deg(end));
                app.ScapularElevationEditField.Value=(j2_deg(end));
                app.ShoulderRorationEditField.Value=(j4_deg(end));
                app.ShoulderAbductionEditField.Value=(j3_deg(end));
                app.ShoulderFlexionEditField.Value=(j5_deg(end));
                app.ElbowFlexionEditField.Value=(j6_deg(end));


                %% ROLL PITCH YAW
                [~, roll]   = instObj.getCallbackDataForSignal(eventData, 'roll','Decimation',100);
                [~, pitch]   = instObj.getCallbackDataForSignal(eventData, 'pitch','Decimation',100);
                [~, yaw]   = instObj.getCallbackDataForSignal(eventData, 'yaw','Decimation',100);
                app.ROLLEditField.Value=string(roll(end));
                app.YAWEditField.Value=string(yaw(end));
                app.PITCHEditField.Value=string(pitch(end));

                %homing status
                [~,status_1]   = instObj.getCallbackDataForSignal(eventData, 'status_1','Decimation',100);
                [~,status_2]   = instObj.getCallbackDataForSignal(eventData, 'status_2','Decimation',100);
                [~,status_3]   = instObj.getCallbackDataForSignal(eventData, 'status_3','Decimation',100);
                [~,status_4]   = instObj.getCallbackDataForSignal(eventData, 'status_4','Decimation',100);
                [~,status_5]   = instObj.getCallbackDataForSignal(eventData, 'status_5','Decimation',100);
                [~,status_6]   = instObj.getCallbackDataForSignal(eventData, 'status_6','Decimation',100);
                app.J1_HomingLamp.Color = app.get_homing(status_1);
                app.J2_HomingLamp.Color = app.get_homing(status_2);
                app.J3_HomingLamp.Color = app.get_homing(status_3);
                app.J4_HomingLamp.Color = app.get_homing(status_4);
                app.J5_HomingLamp.Color = app.get_homing(status_5);
                app.J6_HomingLamp.Color = app.get_homing(status_6);


                [~,WorkingChartState]   = instObj.getCallbackDataForSignal(eventData, 'WorkingChartState','Decimation',100);


                switch WorkingChartState
                    case 1
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\sleep_mode.png";
                    case 2
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\homeWIP.png";
                    case 3
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\Homing.png";
                    case 4
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\trasparenza.png";
                    case 5
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\Flexion_Disactive.svg";

                    case 6
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\Flexion_Disactive.svg";
                    case 7
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\Elbow_Flexion_Extension_Disactive.svg";

                    case 8
                          app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\ExDone.png";
                    case 10
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\FaultError.png";
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',200);
                        uiwait(msgbox("Fault Detected","Error","error"));
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',100);
                    case 11
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\LIP.png";
                    case 12
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\record.png";

                    case 13
                        app.StatusImage.ImageSource="\REPO_SW\pictures_for_GUI\replay.png";

                end


 [~,DownUpMsg]   = instObj.getCallbackDataForSignal(eventData, 'DownUpMsg','Decimation',100);
 [~,DownUpMsgEl]   = instObj.getCallbackDataForSignal(eventData, 'DownUpMsgEl','Decimation',100);

 if WorkingChartState==6
     switch DownUpMsg
         case 1 %display arrow down
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\DOWN.png";
         case 2 %display arrow up
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\UP.png";
         case 0 %display loading  
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\LOAD.png";
     end
 elseif WorkingChartState==7
     switch DownUpMsgEl
         case 1 %display arrow down
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\DOWN.png";
         case 2 %display arrow up
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\UP.png";
         case 0 %display loading
             app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\LOAD.png";
     end
 else
     app.Image20.ImageSource="\REPO_SW\pictures_for_GUI\LOAD.png";

 end

            end
        end

            function value = get_statusword(~,stat)
                stat=dec2bin(stat,16);
                statusword_num=str2num(stat');
                interesting_bits=statusword_num([10 11 13:16])';
                value='';
                if isequal(interesting_bits,[0 0 0 0 0 0]) % not ready to switch on
                    value = 'Not ready to switch on';
                elseif isequal(interesting_bits,[1 0 0 0 0 0])
                    value = 'Switch on disabled';
                elseif isequal(interesting_bits,[0 1 0 0 0 1])
                    value = 'Ready to switch on';
                elseif isequal(interesting_bits,[0 1 0 0 1 1])
                    value = 'Switched on';
                elseif isequal(interesting_bits,[0 1 0 1 1 1])
                    value = 'Operation enabled';
                elseif isequal(interesting_bits,[0 0 0 1 1 1])
                    value = 'Quick stop active';
                elseif isequal(interesting_bits,[0 0 1 1 1 1])
                    value = 'Fault reaction active';
                elseif isequal(interesting_bits,[0 0 1 0 0 0])
                    value = 'Fault';
                end
            end

            function value = get_error(app,error_code)
                value='';
                last_errorcode=dec2hex(error_code,4);
                error_table_index=find(contains(app.errorcode.Errorcode.Errorcodehex,last_errorcode),1);
                value = [last_errorcode ' : ' app.errorcode.Errorcode.Name{error_table_index}];
            end

            function value = get_NMT_state(~,nmt_state)
                value='';
                switch nmt_state
                    case hex2dec('00')
                        value = 'Bootup';
                    case hex2dec('04')
                        value = 'Stopped';
                    case hex2dec('05')
                        value = 'Operational';
                    case hex2dec('7F')
                        value = 'Pre-operational';
                end
            end

            function value = get_mode(~,mode)
                value='';
                switch mode
                    case 1
                        value = 'Profile Position Mode (PPM)';
                    case 3
                        value = 'Profile Velocity Mode (PPM)';
                    case 6
                        value = 'Homing Mode (HMM)';
                    case 8
                        value = 'Cyclic Synchronous Position Mode (CSP)';
                    case 9
                        value = 'Cyclic Synchronous Velocity Mode (CSV)';
                    case 10
                        value = 'Cyclic Synchronous Torque Mode (CST)';
                end
            end

            %         function write_ATI_text(app, instObj, eventData)
            %             % ATI data
            %             [~, F_T]   = instObj.getCallbackDataForSignal(eventData, 'DOF6_EPOS_TIVA/ATI/convert_units',1,'Decimation',100);
            %
            %             app.FxEditField.Value = string(F_T(end,1));
            %             app.FyEditField.Value = string(F_T(end,2));
            %             app.FzEditField.Value = string(F_T(end,3));
            %             app.TxEditField.Value = string(F_T(end,4));
            %             app.TyEditField.Value = string(F_T(end,5));
            %             app.TzEditField.Value = string(F_T(end,6));
            %
            %         end

            function write_pos_control(app, instObj, eventData)
                [~, pos_3_control]   = instObj.getCallbackDataForSignal(eventData, 'pos_3_control',1,'Decimation',100);
                [~, pos_4_control]   = instObj.getCallbackDataForSignal(eventData, 'pos_4_control',1,'Decimation',100);
                [~, pos_5_control]   = instObj.getCallbackDataForSignal(eventData, 'pos_5_control',1,'Decimation',100);

                app.PoscontrolM3EditField.Value = string(pos_3_control(end));
                app.PoscontrolM4EditField.Value = string(pos_4_control(end));
                app.PoscontrolM5EditField.Value = string(pos_5_control(end));
            end

            %         function write_JOY_text(app, instObj, eventData)
            %             % ATI data
            %             [~, J_inc]   = instObj.getCallbackDataForSignal(eventData, 'DOF6_EPOS_TIVA/From Joistick/Joi_gain',1,'Decimation',100);
            %
            %             app.Joy_1EditField.Value = string(J_inc(end,1));
            %             app.Joy_2EditField.Value = string(J_inc(end,2));
            %             app.Joy_3EditField.Value = string(J_inc(end,3));
            %             app.Joy_4EditField.Value = string(J_inc(end,4));
            %             app.Joy_5_upEditField.Value = string(J_inc(end,5));
            %             app.Joy_5_downEditField.Value = string(J_inc(end,6));
            %
            %         end

            function set_speed(app)
                %M1
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/ParamData','Value',100); %%set reference velocity m1

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);

                pause(0.1)


                %M2
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/ParamData','Value',100); %%set reference velocity m2

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);

                pause(0.1)

                %M3
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/ParamData','Value',100); %%set reference velocity m3

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);

                pause(0.1)


                %M4
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/ParamData','Value',100); %%set reference velocity m4

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);

                pause(0.1)

                %M5
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/ParamData','Value',100); %%set reference velocity m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);

                pause(0.1)

                %M6
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Index','Value',hex2dec('6081'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/ParamData','Value',100); %%set reference velocity m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);

                pause(0.1)
            end

            function set_acceleration(app)
                %M1
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/ParamData','Value',100); %%set reference acc m1

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);

                pause(0.1)


                %M2
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/ParamData','Value',100); %%set reference acc m2

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);

                pause(0.1)

                %M3
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/ParamData','Value',100); %%set reference acc m3

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);

                pause(0.1)


                %M4
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/ParamData','Value',100); %%set reference acc m4

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);

                pause(0.1)

                %M5
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/ParamData','Value',100); %%set reference acc m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);

                pause(0.1)
                %M6
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Index','Value',hex2dec('6083'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Subindex','Value',hex2dec('00'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/ParamData','Value',100); %%set reference acc m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);

                pause(0.1)
            end

            function limits_inc(app)
                %% setting lower limits in incs

                lower_limit_incs_m1 = -313180+2*7111;%10 deg to be conservative
                lower_limit_incs_m2 = -246224+2*5461;
                lower_limit_incs_m3 = -137417+2*2276;
                lower_limit_incs_m4 = -157284+2*2276;
                lower_limit_incs_m5 = -142915+2*2276;
                lower_limit_incs_m6 = -204877+2*2276; % check

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/ParamData','Value',lower_limit_incs_m1); %%set lower limit m1

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/ParamData','Value',lower_limit_incs_m2); %%set lower limit m2

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/ParamData','Value',lower_limit_incs_m3); %%set lower limit m3

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/ParamData','Value',lower_limit_incs_m4); %%set lower limit m4

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/ParamData','Value',lower_limit_incs_m5); %%set lower limit m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Subindex','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/ParamData','Value',lower_limit_incs_m6); %%set lower limit m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);

                pause(0.1)

                %% setting upper limits in incs

                upper_limit_incs_m1 = 326829-2*7111;
                upper_limit_incs_m2 = 106389-2*5461;
                upper_limit_incs_m3 = 169503-2*2276;
                upper_limit_incs_m4 = 215159-2*2276;
                upper_limit_incs_m5 = 257147-2*2276;
                upper_limit_incs_m6 = 101246-2*2276;

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO/ParamData','Value',upper_limit_incs_m1); %%set lower limit m1

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/rxSDO2/ParamData','Value',upper_limit_incs_m2); %%set lower limit m2

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/send_rx_SDO2','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO/ParamData','Value',upper_limit_incs_m3); %%set lower limit m3

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO2/ParamData','Value',upper_limit_incs_m4); %%set lower limit m4

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO2','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO3/ParamData','Value',upper_limit_incs_m5); %%set lower limit m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO3','Value',0);

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/CS','Value',hex2dec('22'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Index','Value',hex2dec('607D'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/Subindex','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/rxSDO4/ParamData','Value',upper_limit_incs_m6); %%set lower limit m5

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_rx_SDO4','Value',0);

                pause(0.1)

            end

            function value = get_target_reached(~,stat,mode)
                stat=dec2bin(stat,16);
                statusword_num=str2num(stat');
                interesting_bits=statusword_num(6)';

                if(strcmp(mode,'Profile Position Mode (PPM)'))
                    if isequal(interesting_bits,1)
                        value = [0 1 0];
                    elseif isequal(interesting_bits,0)
                        value = [1 0 0];
                    end
                else
                    value=[1 1 1];
                end
            end

            function value = get_homing(~,stat)

                if stat==0
                    value = [1 0 0];
                elseif stat==1
                    value = [1.00,0.41,0.16];

                else
                    value=[0.00,1.00,0.00];
                end

                %         function getCartesian(app, instObj, eventData)
                %             [~, x_ee]   = instObj.getCallbackDataForSignal(eventData, 'DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/actual_x','Decimation',100);
                %             [~, y_ee]   = instObj.getCallbackDataForSignal(eventData, 'DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/actual_y','Decimation',100);
                %             [~, z_ee]   = instObj.getCallbackDataForSignal(eventData, 'DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/actual_z','Decimation',100);
                %
                %             app.xEditField.Value = string(x_ee(end));
                %             app.yEditField.Value = string(y_ee(end));
                %             app.zEditField.Value = string(z_ee(end));
                %         end
            end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadButton.TargetSource = app.TargetSelector;            app.SystemLog.TargetSource = app.TargetSelector;
            app.StartStopButton.TargetSource = app.TargetSelector;
            app.ConnectButton.TargetSource = app.TargetSelector;

            app.LoadButton.Application = app.SLRTApp;

            app.errorcode=load('errorcode',"Errorcode");

            app.tg = slrealtime;
            app.Instrument = slrealtime.Instrument;

            %             app.Instrument.connectCallback(@app.updateAnimationCallback);

            addSignal(app.Instrument,'statusword_1','Decimation',100)
            app.Instrument.addSignal('statusword_2','Decimation',100)
            app.Instrument.addSignal('statusword_3','Decimation',100)
            app.Instrument.addSignal('statusword_4','Decimation',100)
            app.Instrument.addSignal('statusword_5','Decimation',100)
            app.Instrument.addSignal('statusword_6','Decimation',100)

            app.Instrument.addSignal('error_code_m1','Decimation',100)
            app.Instrument.addSignal('error_code_m2','Decimation',100)
            app.Instrument.addSignal('error_code_m3','Decimation',100)
            app.Instrument.addSignal('error_code_m4','Decimation',100)
            app.Instrument.addSignal('error_code_m5','Decimation',100)
            app.Instrument.addSignal('error_code_m6','Decimation',100)

            app.Instrument.addSignal('NMT_state_1','Decimation',100)
            app.Instrument.addSignal('NMT_state_2','Decimation',100)
            app.Instrument.addSignal('NMT_state_3','Decimation',100)
            app.Instrument.addSignal('NMT_state_4','Decimation',100)
            app.Instrument.addSignal('NMT_state_5','Decimation',100)
            app.Instrument.addSignal('NMT_state_6','Decimation',100)

            app.Instrument.addSignal('mode_1','Decimation',100)
            app.Instrument.addSignal('mode_2','Decimation',100)
            app.Instrument.addSignal('mode_3','Decimation',100)
            app.Instrument.addSignal('mode_4','Decimation',100)
            app.Instrument.addSignal('mode_5','Decimation',100)
            app.Instrument.addSignal('mode_6','Decimation',100)

            app.Instrument.addSignal('pos_actual_1','Decimation',100)
            app.Instrument.addSignal('pos_actual_2','Decimation',100)
            app.Instrument.addSignal('pos_actual_3','Decimation',100)
            app.Instrument.addSignal('pos_actual_4','Decimation',100)
            app.Instrument.addSignal('pos_actual_5','Decimation',100)
            app.Instrument.addSignal('pos_actual_6','Decimation',100)

            app.Instrument.addSignal('M1_AKSIM1_TICKS','Decimation',100)
            app.Instrument.addSignal('M2_AKSIM1_TICKS','Decimation',100)
            app.Instrument.addSignal('M3_AKSIM1_TICKS','Decimation',100)
            app.Instrument.addSignal('M4_AKSIM1_TICKS','Decimation',100)
            app.Instrument.addSignal('M5_AKSIM1_TICKS','Decimation',100)
            app.Instrument.addSignal('M6_AKSIM1_TICKS','Decimation',100)

            app.Instrument.addSignal('M1_AKSIM2_TICKS','Decimation',100)
            app.Instrument.addSignal('M2_AKSIM2_TICKS','Decimation',100)
            app.Instrument.addSignal('M3_AKSIM2_TICKS','Decimation',100)
            app.Instrument.addSignal('M4_AKSIM2_TICKS','Decimation',100)
            app.Instrument.addSignal('M5_AKSIM2_TICKS','Decimation',100)
            app.Instrument.addSignal('M6_AKSIM2_TICKS','Decimation',100)

            app.Instrument.addSignal('j1_deg','Decimation',100)
            app.Instrument.addSignal('j2_deg','Decimation',100)
            app.Instrument.addSignal('j3_deg','Decimation',100)
            app.Instrument.addSignal('j4_deg','Decimation',100)
            app.Instrument.addSignal('j5_deg','Decimation',100)
            app.Instrument.addSignal('j6_deg','Decimation',100)

            app.Instrument.addSignal('roll','Decimation',100)
            app.Instrument.addSignal('pitch','Decimation',100)
            app.Instrument.addSignal('yaw','Decimation',100)

            app.Instrument.addSignal('status_1','Decimation',100)
            app.Instrument.addSignal('status_2','Decimation',100)
            app.Instrument.addSignal('status_3','Decimation',100)
            app.Instrument.addSignal('status_4','Decimation',100)
            app.Instrument.addSignal('status_5','Decimation',100)
            app.Instrument.addSignal('status_6','Decimation',100)
            
            app.Instrument.addSignal('WorkingChartState','Decimation',100)
            app.Instrument.addSignal('DownUpMsg','Decimation',100)
            app.Instrument.addSignal('DownUpMsgEl','Decimation',100)
           

            app.Instrument.connectCallback(@app.get_status_error_nmt)


            Inst_6 = slrealtime.Instrument;
            Inst_6.connectLine(app.UIAxes_T1, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',1);
            Inst_6.connectLine(app.UIAxes_T2, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',2);
            Inst_6.connectLine(app.UIAxes_T3, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',3);
            Inst_6.connectLine(app.UIAxes_T4, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',4);
            Inst_6.connectLine(app.UIAxes_T5, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',5);
            Inst_6.connectLine(app.UIAxes_T6, 'ExoskeletonControlModel/DataProcessing/estim_torque_M1_M6/Sea_estimation',1,'ArrayIndex',6);


            app.Instrument.connectCallback(@app.get_status_error_nmt)
            app.InstrumentManager = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', app.TargetSelector);
            app.InstrumentManager.Instruments = [app.Instrument Inst_6];


            %% parameter table  [Not graphically present but always useful to keep the code ... in case we want to add it again]
            %                  app.ParameterTable.Parameters = struct( ...
            %                 'BlockPath', {'DOF6_EPOS_TIVA/AdmittanceControl/dead_zone','DOF6_EPOS_TIVA/AdmittanceControl/dead_zone','DOF6_EPOS_TIVA/AdmittanceControl/Gain_all',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/rate_lim','DOF6_EPOS_TIVA/AdmittanceControl/rate_lim',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/stauration_1','DOF6_EPOS_TIVA/AdmittanceControl/stauration_1',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/stauration_2','DOF6_EPOS_TIVA/AdmittanceControl/stauration_2',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/stauration_3','DOF6_EPOS_TIVA/AdmittanceControl/stauration_3',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/stauration_4','DOF6_EPOS_TIVA/AdmittanceControl/stauration_4',...
            %                 'DOF6_EPOS_TIVA/AdmittanceControl/stauration_5','DOF6_EPOS_TIVA/AdmittanceControl/stauration_5',}, ...
            %                 'ParameterName', {'LowerValue','UpperValue','Gain','RisingSlewLimit','FallingSlewLimit',...
            %                 'UpperLimit','LowerLimit',...
            %                 'UpperLimit','LowerLimit',...
            %                 'UpperLimit','LowerLimit',...
            %                 'UpperLimit','LowerLimit',...
            %                 'UpperLimit','LowerLimit'});
        end

        % Selection changed function: setNMTstatebothmotorsButtonGroup
        function setNMTstatebothmotorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.setNMTstatebothmotorsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Enter Pre-Operational')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('80'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('80'));
            elseif strcmp(selectedButton.Text,'Reset Communication')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('82'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('82'));
            elseif strcmp(selectedButton.Text,'Reset Node')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('81'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('81'));
            elseif strcmp(selectedButton.Text,'Start Remote Node')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('01'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('01'));
            elseif strcmp(selectedButton.Text,'Stop Remote Node')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('02'));
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('02'));
            end
        end

        % Selection changed function: setModem1ButtonGroup
        function setModem1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem1ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',10);
            end
        end

        % Selection changed function: setModem2ButtonGroup
        function setModem2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem2ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',10);
            end
        end

        % Selection changed function: controlwordm1ButtonGroup
        function controlwordm1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm1ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('007F'));
            end
        end

        % Selection changed function: controlwordm2ButtonGroup
        function controlwordm2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm2ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('007F'));
            end
        end

        % Callback function
        function OffsetincrementsButtonPushed(app, event)
            % not used but the button is still present... maybe we can delate
            %             controlwordm1ButtonGroupSelectionactual_pos_1_incremental=app.tg.getsignal('RX_EPOS4/CAN Read Loop - Port 2/TxPDO2/p1');
            %             actual_pos_2_incremental=app.tg.getsignal('RX_EPOS4/CAN Read Loop - Port 2/TxPDO2_m2/p1');
            %
            %             aksim_input_M1_deg=app.tg.getsignal('IncrementsAfterOffset1/s1');
            %             aksim_input_M2_deg=app.tg.getsignal('IncrementsAfterOffset2/s1');
            %
            %             pos_actual_M1_inc=app.tg.getsignal('');
            %             pos_actual_M2_inc=app.tg.getsignal('');
            %
            %             %             offset1=actual_pos_1_incremental-
            %             %             offset2=actual_pos_2_incremental-
            %             app.tg.setparam('offsetM1','Value',offset1);
            %             app.tg.setparam('offsetM2','Value',offset2);
        end

        % Callback function
        function Damper1EditFieldValueChanged(app, event)
            value = app.Damper1EditField.Value;
            app.damper1=value;
        end

        % Callback function
        function Damper2EditFieldValueChanged(app, event)
            value = app.Damper2EditField.Value;
            app.damper2=value;
        end

        % Selection changed function: setModem3ButtonGroup
        function setModem3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem3ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',10);
            end
        end

        % Selection changed function: controlwordm3ButtonGroup
        function controlwordm3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm3ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('007F'));
            end
        end

        % Callback function
        function Damper3EditFieldValueChanged(app, event)
            value = app.Damper3EditField.Value;
            app.damper3=value;
        end

        % Callback function
        function k1EditFieldValueChanged(app, event)
            value = app.k1EditField.Value;
            app.k1=value;
        end

        % Callback function
        function k2EditFieldValueChanged(app, event)
            value = app.k2EditField.Value;
            app.k2=value;
        end

        % Callback function
        function k3EditFieldValueChanged(app, event)
            value = app.k3EditField.Value;
            app.k3=value;
        end

        % Callback function
        function VISCOELASTICO1SwitchValueChanged(app, event)
            value = app.VISCOELASTICO1Switch.Value;

            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b01','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a01','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper1>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b01','Value',1/app.damper1);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a01','Value',app.k1/app.damper1);
                    elseif(app.damper1==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b01','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a01','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper1>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',1/app.damper1);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',app.k1/app.damper1);
                    elseif(app.damper1==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            end

        end

        % Callback function
        function VISCOELASTICO2SwitchValueChanged(app, event)
            value = app.VISCOELASTICO2Switch.Value;
            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b02','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a02','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper2>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b02','Value',1/app.damper2);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a02','Value',app.k2/app.damper2);
                    elseif(app.damper2==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b02','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a02','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper2>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',1/app.damper2);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',app.k2/app.damper2);
                    elseif(app.damper2==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            end

        end

        % Callback function
        function VISCOELASTICO3SwitchValueChanged(app, event)
            value = app.VISCOELASTICO3Switch.Value;
            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b03','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a03','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper3>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b03','Value',1/app.damper3);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a03','Value',app.k3/app.damper3);
                    elseif(app.damper3==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b03','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a03','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper3>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',1/app.damper3);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',app.k3/app.damper3);
                    elseif(app.damper3==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            end


        end

        % Selection changed function: controlwordm4ButtonGroup
        function controlwordm4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm4ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('007F'));
            end
        end

        % Selection changed function: controlwordm5ButtonGroup
        function controlwordm5ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm5ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('007F'));
            end

        end

        % Selection changed function: setModem4ButtonGroup
        function setModem4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem4ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',10);
            end
        end

        % Selection changed function: setModem5ButtonGroup
        function setModem5ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem5ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',10);
            end
        end

        % Callback function
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            switch value
                case 'On'
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('80')); %%setting M1-M2 'Pre-Operational' mode
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('80')); %%setting M3-M5 'Pre-Operational' mode

                    pause(0.5)

                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('01')); %%setting M1-M2 'Operational' mode
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('01')); %%setting M3-M5 'Operational' mode

                    pause(0.5)

                    %Shout - down - Enable operation - Switch on & Enable operation
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0006')); %%set 'shoutdown' m1
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0006')); %%set 'shoutdown' m2
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0006')); %%set 'shoutdown' m3
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0006')); %%set 'shoutdown' m4
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0006')); %%set 'shoutdown' m5

                    pause(0.5)

                    % Setting Mode
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',1); %%setting M1-M2 in 'Profile Position Mode (PPM)'
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',1);
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',1); %%setting M3-M5 in 'Profile Position Mode (PPM)'
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',1);
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',1);

                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %%setting M1-M2 in 'CSP'
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8);
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %%setting M3-M5 in 'CSP'
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8);
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8);

                    pause(0.5)

                    %switch on % enable
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F')); %%set ''Switch on & Enable Operation' m1
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F')); %%set 'Enable Operation' m2
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F')); %%set 'Enable Operation' m3
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F')); %%set 'Enable Operation' m4
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F')); %%set 'Enable Operation' m5


                case 'Off'

                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('0006')); %%set 'shoutdown' m1
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('0006')); %%set 'shoutdown' m2
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('0006')); %%set 'shoutdown' m3
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('0006')); %%set 'shoutdown' m4
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('0006')); %%set 'shoutdown' m5

                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/CS','Value',hex2dec('80')); %%setting M1-M2 'Pre-Operational' mode
                    app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/CS','Value',hex2dec('80')); %%setting M3-M5 'Pre-Operational' mode
            end


        end

        % Callback function
        function StartATIButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',0);

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Send/ATI_Command','Value',uint8(2));

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',1);
        end

        % Callback function
        function StopATIButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',0);

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Send/ATI_Command','Value',uint8(0));

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',1);
        end

        % Callback function
        function BiasATIButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',0);

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Send/ATI_Command','Value',uint8(66));

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',1);
        end

        % Callback function
        function UnBiasATIButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',0);

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Send/ATI_Command','Value',uint8(65));

            app.tg.setparam('DOF6_EPOS_TIVA/ATI/Trigger','Value',1);
        end

        % Value changed function: WakeUpFLOATEvoSwitch
        function WakeUpFLOATEvoSwitchValueChanged(app, event)
            value = app.WakeUpFLOATEvoSwitch.Value;
            switch value
                case 'On'
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/Enable_homing','Value',1);


                case 'Off'
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/Enable_homing','Value',0);

            end
        end

        % Callback function
        function ControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlButtonGroup.SelectedObject;

            if (strcmp(selectedButton.Text,'GravityCompensation'))

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/Subsystem/reset_int','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/enable_test_grav','Value',1);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',2);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

                app.flag_gravity=1;


            elseif (strcmp(selectedButton.Text,'VectorialField'))
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl1','Gain',0);
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl2','Gain',0);
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl3','Gain',0);
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl4','Gain',0);
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl5','Gain',0);
                %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl6','Gain',0);
                %            app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Flag_int','Value',1);
                % app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Flag_int','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Flag_int','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F'));  %enable operations
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F'));   %enable operations
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F'));   %enable operations
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F'));   %enable operations
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F'));   %enable operations
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F'));   %enable operations

                pause(1)


                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',10); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',10); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',10); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',10); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',10); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',10); %set interpolation time period to 10ms

                pause(0.1)


                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/send_pos','Value',1);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);
            else
                app.tg.setparam('DOF6_EPOS_TIVA/ref_pos','Value',8);
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            end

        end

        % Callback function
        function inc_xButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/From Joistick/target_y','Value',0.01);
            pause(0.05);
            app.tg.setparam('DOF6_EPOS_TIVA/From Joistick/target_y','Value',0);

        end

        % Callback function
        function dec_xButtonPushed(app, event)
            app.tg.setparam('DOF6_EPOS_TIVA/From Joistick/target_y','Value',-0.01);
            pause(0.05);

            app.tg.setparam('DOF6_EPOS_TIVA/From Joistick/target_y','Value',0);
        end

        % Callback function
        function VISCOELASTICO4SwitchValueChanged(app, event)
            value = app.VISCOELASTICO4Switch.Value;
            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b04','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a04','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper4>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b04','Value',1/app.damper4);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a04','Value',app.k4/app.damper4);
                    elseif(app.damper4==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b04','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a04','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper4>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',1/app.damper4);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',app.k4/app.damper4);
                    elseif(app.damper4==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            end

        end

        % Callback function
        function VISCOELASTICO5SwitchValueChanged(app, event)
            value = app.VISCOELASTICO5Switch.Value;
            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b05','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a05','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper5>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b05','Value',1/app.damper5);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a05','Value',app.k5/app.damper5);
                    elseif(app.damper5==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b05','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a05','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper5>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',1/app.damper5);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',app.k5/app.damper5);
                    elseif(app.damper5==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',0);
            end

        end

        % Callback function
        function Damper4EditFieldValueChanged(app, event)
            value = app.Damper4EditField.Value;
            app.damper4=value;
        end

        % Callback function
        function Damper5EditFieldValueChanged(app, event)
            value = app.Damper5EditField.Value;
            app.damper5=value;
        end

        % Callback function
        function k4EditFieldValueChanged(app, event)
            value = app.k4EditField.Value;
            app.k4=value;
        end

        % Callback function
        function k5EditFieldValueChanged(app, event)
            value = app.k5EditField.Value;
            app.k5=value;
        end

        % Callback function
        function x0EditFieldValueChanged(app, event)
            value = app.x0EditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/X0','Value',value);
        end

        % Callback function
        function y0EditFieldValueChanged(app, event)
            value = app.y0EditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/Y0','Value',value);
        end

        % Callback function
        function z0EditFieldValueChanged(app, event)
            value = app.z0EditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/Z0','Value',value);
        end

        % Callback function
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/a','Value',value);
        end

        % Callback function
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/b','Value',value);
        end

        % Callback function
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/Velocity_field/Cartesian Control/c','Value',value);
        end

        % Callback function
        function J5o1EditFieldValueChanged(app, event)
            value = app.J5o1EditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/RX/CAN Read Loop - Channel 2 - M3-M5/J5o1','Value',value);
        end

        % Callback function
        function J5o1EditFieldValueChanged2(app, event)
            value = app.J5o1EditField.Value;
            %app.tg.setparam('DOF6_EPOS_TIVA/RX/CAN Read Loop - Channel 2 - M3-M5/J5o1','Value',value);
            app.tg.setparam('DOF6_EPOS_TIVA/estim_torque_M1_M2/Off_5','Value',value);
        end

        % Callback function
        function FieldgainEditFieldValueChanged(app, event)
            value = app.FieldgainEditField.Value;
            %             app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/vxg','Gain',value);
            %             app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/vyg','Gain',value);
            %             app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/vzg','Gain',value);

            app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/wxg','Gain',value);
            app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/wyg','Gain',value);
            app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/wzg','Gain',value);
        end

        % Callback function
        function IntegratorresetEditFieldValueChanged(app, event)
            value = app.IntegratorresetEditField.Value;
            app.tg.setparam('DOF6_EPOS_TIVA/F_trajectory/Flag_int','Value',value);

        end

        % Callback function
        function AdmittancecontrolButtonGroupSelectionChanged(app, event)
            selectedButton = app.AdmittancecontrolButtonGroup.SelectedObject;
            if (strcmp(selectedButton.Text,'Elastic'))
                app.flag_adm=1;
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command1','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis1','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command2','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis2','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command3','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis3','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command4','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis4','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command5','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis5','Gain',0);

            elseif (strcmp(selectedButton.Text,'ViscousElastic'))
                app.flag_adm=2;
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command1','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis1','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command2','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis2','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command3','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis3','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command4','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis4','Gain',0);

                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/command5','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/admvis5','Gain',0);

            end
        end

        % Callback function
        function offsetm6EditFieldValueChanged(app, event)
            value = app.offsetm6EditField.Value;

            app.tg.setparam('ExoskeletonControlModel/InputDataRX/CAN Read Loop - Channel 2 - M3-M5/DiscontinuityTranslationAks2M6_ticks','Value',value);
        end

        % Callback function
        function VISCOELASTICO6SwitchValueChanged(app, event)
            value = app.VISCOELASTICO6Switch.Value;
            if (app.flag_gravity==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b06','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a06','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper6>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b06','Value',1/app.damper6);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a06','Value',app.k6/app.damper6);
                    elseif(app.damper6==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/b06','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/GravityCompensation/a06','Value',0);
                    end
                end
            elseif  (app.flag_compliant_ex==1)
                if strcmp(value,'Off')  %off
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',0);
                elseif strcmp(value,'On')
                    if(app.damper6>0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/app.damper6);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',app.k6/app.damper6);
                    elseif(app.damper6==0)
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',0);
                        app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',0);
                    end
                end
            else
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',0);
            end
        end

        % Callback function
        function k6EditFieldValueChanged(app, event)
            value = app.k6EditField.Value;
            app.k6=value;
        end

        % Callback function
        function Damper6EditFieldValueChanged(app, event)
            value = app.Damper6EditField.Value;
            app.damper6=value;
        end

        % Callback function
        function VECTORIALFIELDADMITTANCESwitchValueChanged(app, event)
            value = app.VECTORIALFIELDADMITTANCESwitch.Value;

            if strcmp(value,'Off')  %off
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl1','Gain',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl2','Gain',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl3','Gain',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl4','Gain',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl5','Gain',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl6','Gain',0);
            elseif strcmp(value,'On')
                if(app.k1>0 && app.k2>0 && app.k3>0 && app.k4>0 && app.k5>0 && app.k6>0)
                    %    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl1','Gain',1/app.k1);
                    %app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl2','Gain',1/app.k2);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl3','Gain',1/app.k3);
                    %      app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl4','Gain',1/app.k4);
                    %app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl5','Gain',1/app.k5);
                    %                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl6','Gain',1/app.k6);
                else
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl1','Gain',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl2','Gain',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl3','Gain',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl4','Gain',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl5','Gain',0);
                    app.tg.setparam('ExoskeletonControlModel/DataProcessing/VectorialFieldControl/Admittance_single_joint1/AdmEl6','Gain',0);
                end
            end

        end

        % Callback function
        function StartCompliantExerciseButtonGroupSelectionChanged(app, event)
            selectedButton = app.StartCompliantExerciseButtonGroup.SelectedObject;
            if (strcmp(selectedButton.Text,'Abduction'))
                app.flag_compliant_ex=1;
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

                pause(0.1)


                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',1);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            elseif (strcmp(selectedButton.Text,'Flexion'))
                app.flag_compliant_ex=1;
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

                pause(0.1)


                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',2);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            elseif (strcmp(selectedButton.Text,'Circle'))
                app.flag_compliant_ex=1;
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

                pause(0.1)


                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',3);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            elseif (strcmp(selectedButton.Text,'FixedHome'))
                app.flag_compliant_ex=1;
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

                pause(0.1)

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

                pause(0.1)


                app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',4);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);


            else
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            end
        end

        % Size changed function: WELCOMETab
        function WELCOMETabSizeChanged(app, event)

        end

        % Callback function
        function ImageClicked(app, event)
            app.Image.BackgroundColor=[0.18,0.41,0.44];
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            %FLEXION ESTENSION
            app.flag_compliant_ex=1;
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

            pause(0.1)


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/reset_gravity','Value',1);
            pause(0.3)

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/reset_gravity','Value',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart1','Gain',1);


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',1);
            % app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/TR1','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/FlexionEstension/reset_count','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);


            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',1/20);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',1/40);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',1/8);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',1/8);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',1/10);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',1);


            pause(12)
            app.Image.BackgroundColor=[0.94,0.94,0.94];

        end

        % Callback function
        function Image3Clicked(app, event)
            app.Image3.BackgroundColor=[0.18,0.41,0.44];
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            %circonduction
            app.flag_compliant_ex=1;
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

            pause(0.1)


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',2);
            %app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/TR2','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/Circonduction/reset_count','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',1/20);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',1/40);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',1/8);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',1/10);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',1);
            pause(12)
            app.Image3.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function Image2Clicked(app, event)
            app.Image2.BackgroundColor=[0.18,0.41,0.44];
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            %elbow flexion estension
            app.flag_compliant_ex=1;
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

            pause(0.1)


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',3);
            %    app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/TR3','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/ElbowFlexionEstension/reset_count','Value',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',1);
            pause(12)
            app.Image2.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function Image6Clicked(app, event)
            app.Image6.BackgroundColor=[0.85,0.92,0.99];
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            %intra extra rotation
            app.flag_compliant_ex=1;
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

            pause(0.1)


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',4);
            %app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/TR4','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/IntraExtraRotation/reset_count','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',1/20);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',1/40);
            %                 app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',1/10);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',1);
            pause(12)
            app.Image6.BackgroundColor=[0.94,0.94,0.94];
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
             app.Image4.BackgroundColor=[0.85,0.92,0.99];
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1);

            pause(5)
            app.Image4.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function target6PPMButtonPushed(app, event)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',1); %set PPM

            %  app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/target_position_M6','Value',-20000); %relative reference
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',6);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('003F')); %absolute reference

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F')); %absolute reference
            pause(0.1)


            %app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/send_target_pos_m6','Value',1);

        end

        % Callback function
        function Image9Clicked(app, event)
            app.flag_compliant_ex=1;
            app.Image9.BackgroundColor=[0.18,0.41,0.44];

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8); %set CSP

            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m1','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/interpolation_period_m2','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m3','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m4','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m5','Value',2); %set interpolation time period to 10ms
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/interpolation_period_m6','Value',2); %set interpolation time period to 10ms

            pause(0.1)


            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/reset_gravity','Value',1);
            pause(0.3)

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/reset_gravity','Value',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart','Gain',1);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart1','Gain',1);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',6);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);

            pause(0.1)
            

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',1/20);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',1/40);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',1/8);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',1/5);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',0);

            pause(5)
            app.Image9.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function Image10Clicked(app, event)
            app.Image10.BackgroundColor=[0.18,0.41,0.44];
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b01','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a01','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b02','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a02','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b03','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a03','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a04','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b05','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a05','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/b06','Value',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/a06','Value',0);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m1/Restart1','Gain',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m2/Restart1','Gain',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m3/Restart1','Gain',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m4/Restart1','Gain',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m5/Restart1','Gain',0);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Filter_m6/Restart1','Gain',0);


            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F')); %absolute reference

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',1); %set PPM
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',1); %set PPM

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Control_selector','Value',3);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/BasicExercisesCompliantControl/Trajectory_basic_exercise/EXERCISE_SELECTOR','Value',5);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_EXERCISE','Value',1);

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',1);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('003F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('003F')); %absolute reference

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m1','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/controlword_m2','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m3','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m4','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F')); %absolute reference
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F')); %absolute reference
            pause(0.1)
            pause(5)
            app.Image10.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function CSTm6TESTButtonPushed(app, event)
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Subsystem/reset_int_ori','Value',0);
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/SendPositionToEPOS/send_pos','Value',0);

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m1','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m3','Value',10); %set CST
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m4','Value',10); %set CST
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',10); %set CST
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',10); %set CST
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/START_CST','Value',1); %set CST
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/Enable_CST','Value',1); %set enable
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Subsystem/reset_int_ori','Value',1); %reset discrete int


        end

        % Callback function
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/P','Value',value);
        end

        % Callback function
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/I','Value',value);
        end

        % Callback function
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/D','Value',value);
        end

        % Callback function
        function PEditField_2ValueChanged(app, event)
            value = app.PEditField_2.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/P','Value',value);
        end

        % Callback function
        function IEditField_2ValueChanged(app, event)
            value = app.IEditField_2.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/I','Value',value);
        end

        % Callback function
        function DEditField_2ValueChanged(app, event)
            value = app.DEditField_2.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/D','Value',value);
        end

        % Callback function
        function PEditField_3ValueChanged(app, event)
            value = app.PEditField_3.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/P','Value',value);
        end

        % Callback function
        function IEditField_3ValueChanged(app, event)
            value = app.IEditField_3.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/I','Value',value);
        end

        % Callback function
        function DEditField_3ValueChanged(app, event)
            value = app.DEditField_3.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/D','Value',value);
        end

        % Callback function
        function PEditField_4ValueChanged(app, event)
            value = app.PEditField_4.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/P','Value',value);
        end

        % Callback function
        function IEditField_4ValueChanged(app, event)
            value = app.IEditField_4.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/I','Value',value);
        end

        % Callback function
        function DEditField_4ValueChanged(app, event)
            value = app.DEditField_4.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/D','Value',value);
        end

        % Callback function
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/N','Value',value);
        end

        % Callback function
        function NEditField_2ValueChanged(app, event)
            value = app.NEditField_2.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/N','Value',value);
        end

        % Callback function
        function NEditField_3ValueChanged(app, event)
            value = app.NEditField_3.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/N','Value',value);
        end

        % Callback function
        function NEditField_4ValueChanged(app, event)
            value = app.NEditField_4.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/N','Value',value);
        end

        % Selection changed function: controlwordm6ButtonGroup
        function controlwordm6ButtonGroupSelectionChanged(app, event)
            selectedButton = app.controlwordm6ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Shutdown')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0006'));
            elseif strcmp(selectedButton.Text,'Switch On')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Switch On & Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Disable Voltage')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0000'));
            elseif strcmp(selectedButton.Text,'Quick stop')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0002'));
            elseif strcmp(selectedButton.Text,'Disable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0007'));
            elseif strcmp(selectedButton.Text,'Enable Operation')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('000F'));
            elseif strcmp(selectedButton.Text,'Fault reset')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0080'));
            elseif strcmp(selectedButton.Text,'Halt')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('0100'));
            elseif strcmp(selectedButton.Text,'Relative Pos Start')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m6','Value',hex2dec('007F'));
            end

        end

        % Selection changed function: setModem6ButtonGroup
        function setModem6ButtonGroupSelectionChanged(app, event)
            selectedButton = app.setModem6ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Profile Position Mode (PPM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',1);
            elseif strcmp(selectedButton.Text,'Profile Velocity Mode (PVM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',3);
            elseif strcmp(selectedButton.Text,'Homing Mode (HMM)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',6);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Position Mode (CSP)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',8);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Velocity Mode (CSV)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',9);
            elseif strcmp(selectedButton.Text,'Cyclic Synchronous Torque Mode (CST)')
                app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m6','Value',10);
            end
        end

        % Callback function
        function EnableCouplingButtonPushed(app, event)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP J2
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/EN_Coupling','Value',1); %enable coupling pitch to J2
            pause(0.1)
        end

        % Callback function
        function enablecouplingButtonPushed(app, event)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M1_M2/mode_m2','Value',8); %set CSP J2
            pause(0.1)
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/EN_Coupling','Value',1); %enable coupling pitch to J2
            pause(0.1)
        end

        % Callback function
        function WaitforsignalButtonPushed(app, event)
            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/mode_m5','Value',1); %set PPM
            pause(0.1)

            app.tg.setparam('ExoskeletonControlModel/OutputDataTX/TX_EPOS4_M3_M5/controlword_m5','Value',hex2dec('000F')); %absolute reference
            pause(0.1)
        end

        % Image clicked function: Image11
        function Image11Clicked(app, event)
             app.Image11.BackgroundColor=[0.85,0.92,0.99];
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',2);
app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',0);

            pause(5)
            app.Image11.BackgroundColor=[0.94,0.94,0.94];
        end

        % Callback function
        function Stiffness_SW_stop3EditFieldValueChanged(app, event)
            value = app.Stiffness_SW_stop3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/Stiffness_SW_stop','Value',value); %set enable
        end

        % Callback function
        function Damper_SW_stop3EditFieldValueChanged(app, event)
            value = app.Damper_SW_stop3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/Damper_SW_stop','Value',value); %set enable

        end

        % Callback function
        function Stiffness_SW_stop_soft3EditFieldValueChanged(app, event)
            value = app.Stiffness_SW_stop_soft3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/Stiffness_SW_stop','Value',value); %set enable

        end

        % Callback function
        function Enable_stop4EditFieldValueChanged(app, event)
            value = app.Enable_stop4EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/Enable_stop','Gain',value); %set enable
        end

        % Callback function
        function Enable_stop3EditFieldValueChanged(app, event)
            value = app.Enable_stop3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/Enable_stop','Gain',value); %set enable
        end

        % Callback function
        function Stiffness_SW_stop4EditFieldValueChanged(app, event)
            value = app.Stiffness_SW_stop4EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/Stiffness_SW_stop','Value',value); %set enable
        end

        % Callback function
        function Damper_SW_stop54EditFieldValueChanged(app, event)
            value = app.Damper_SW_stop54EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/Damper_SW_stop','Value',value); %set enable
        end

        % Callback function
        function Stiffness_SW_stop_soft4EditFieldValueChanged(app, event)
            value = app.Stiffness_SW_stop_soft4EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR4/Stiffness_SW_stop','Value',value); %set enable

        end

        % Callback function
        function Beta_M3EditFieldValueChanged(app, event)
            value = app.Beta_M3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/Beta_M3','Value',value); %set enable

        end

        % Callback function
        function Image_2Clicked(app, event)
            app.Image_2.BackgroundColor=[0.18,0.41,0.44];
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',3);


            pause(5)
            app.Image_2.BackgroundColor=[0.94,0.94,0.94];


        end

        % Callback function
        function Beta_impEditFieldValueChanged(app, event)
            value = app.Beta_impEditField.Value;
           
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/d5','Value',value);

        end

        % Callback function
        function K_impEditFieldValueChanged(app, event)
            value = app.K_impEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/s5','Value',value);
        end

        % Callback function
        function my_j3EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function GainT3EditFieldValueChanged(app, event)
            value = app.GainT3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR3/my_j3','Gain',value)
        end

        % Callback function
        function beta6EditFieldValueChanged(app, event)
            value = app.beta6EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/d6','Value',value);
        end

        % Callback function
        function beta4EditFieldValueChanged(app, event)
            value = app.beta4EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/d4','Value',value);
        end

        % Callback function
        function beta3EditFieldValueChanged(app, event)
            value = app.beta3EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/d3','Value',value);
        end

        % Callback function
        function Pos2torqueEditFieldValueChanged(app, event)
            value = app.Pos2torqueEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/Pos2Torque','Gain',value);
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
             app.Image_3.BackgroundColor=[0.85,0.92,0.99];  
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
              pause(2)
              app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',4);
             pause(5)
            app.Image_3.BackgroundColor=[0.94,0.94,0.94];
        end

        % Value changed function: RepetitionsSpinner
        function RepetitionsSpinnerValueChanged(app, event)
            value = app.RepetitionsSpinner.Value;
       %     app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/Repetition','Value',value);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/Repetition','Value',value);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/Repetition','Value',value);
            
        end

        % Value changed function: TimingsecSpinner
        function TimingsecSpinnerValueChanged(app, event)
            value = app.TimingsecSpinner.Value;
%             app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/duration','Value',value);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/duration','Value',value);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/duration','Value',value);
        end

        % Value changed function: TargetSpinner
        function TargetSpinnerValueChanged(app, event)
            value = app.TargetSpinner.Value;
            %app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/max','Value',value);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR6/max','Value',value);

            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/max','Value',value);
        end

        % Image clicked function: Image2_2
        function Image2_2Clicked(app, event)
            app.Image2_2.BackgroundColor=[0.85,0.92,0.99]; 
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
             pause(2)
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',5);
             pause(5)
             app.Image2_2.BackgroundColor=[0.94,0.94,0.94];
             
        end

        % Callback function
        function k6EditField_2ValueChanged(app, event)
            value = app.k6EditField_2.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/ShoulderFlexionImpedance/s6','Value',value);
        end

        % Image clicked function: Image12
        function Image12Clicked(app, event)
            app.Image12.BackgroundColor=[0.85,0.92,0.99];
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
             pause(2)
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',6);
             pause(5)
             app.Image12.BackgroundColor=[0.94,0.94,0.94];
        end

        % Image clicked function: Image15
        function Image15Clicked(app, event)
              app.Image15.BackgroundColor=[0.85,0.92,0.99]; 
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
             pause(2)
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',7);
             pause(5)
             app.Image15.BackgroundColor=[0.94,0.94,0.94];
        end

        % Image clicked function: Image17
        function Image17Clicked(app, event)
              app.Image17.BackgroundColor=[0.85,0.92,0.99]; 
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
             pause(2)
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',8);
             pause(5)
             app.Image17.BackgroundColor=[0.94,0.94,0.94];
        end

        % Image clicked function: Image16
        function Image16Clicked(app, event)
              app.Image16.BackgroundColor=[0.85,0.92,0.99]; 
              app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',0);
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',1000);
             pause(2)
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Mode_selector','Value',9);
             pause(5)
             app.Image16.BackgroundColor=[0.94,0.94,0.94];
        end

        % Selection changed function: ASSISTANCELEVELButtonGroup
        function ASSISTANCELEVELButtonGroupSelectionChanged(app, event)
            selectedButton = app.ASSISTANCELEVELButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Low')
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/AssistanceLevel','Value',1);  
               app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',1000);
            elseif strcmp(selectedButton.Text,'Medium')
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/AssistanceLevel','Value',2);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',1500);
            elseif strcmp(selectedButton.Text,'High')
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/AssistanceLevel','Value',4);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',0);
            elseif strcmp(selectedButton.Text,'None')
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/AssistanceLevel','Value',0);
                app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',0);
            end
        end

        % Callback function
        function BETA5_TESTEditFieldValueChanged(app, event)
            value = app.BETA5_TESTEditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/betaTest','Value',value);
        end

        % Callback function
        function TESTGAIN5EditFieldValueChanged(app, event)
            value = app.TESTGAIN5EditField.Value;
            app.tg.setparam('ExoskeletonControlModel/DataProcessing/CST_trasparency/CST_control/MOTOR5/PassiveGain','Gain',value);
        end

        % Callback function
        function backEditFieldValueChanged(app, event)
            value = app.backEditField.Value;
             app.tg.setparam('ExoskeletonControlModel/DataProcessing/Gain','Gain',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.STARTUPTab = uitab(app.TabGroup2);
            app.STARTUPTab.Title = 'START UP';
            app.UIAxes_T1 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T1, 'SEA Torque M1')
            xlabel(app.UIAxes_T1, 'Time [s]')
            ylabel(app.UIAxes_T1, '\tau [Nm]')
            zlabel(app.UIAxes_T1, 'Z')
            app.UIAxes_T2 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T2, 'SEA Torque M2')
            xlabel(app.UIAxes_T2, 'Time [s]')
            ylabel(app.UIAxes_T2, '\tau [Nm]')
            zlabel(app.UIAxes_T2, 'Z')
            app.UIAxes_T3 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T3, 'SEA Torque M3')
            xlabel(app.UIAxes_T3, 'Time [s]')
            ylabel(app.UIAxes_T3, '\tau [Nm]')
            zlabel(app.UIAxes_T3, 'Z')
            app.UIAxes_T4 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T4, 'SEA Torque M4')
            xlabel(app.UIAxes_T4, 'Time [s]')
            ylabel(app.UIAxes_T4, '\tau [Nm]')
            zlabel(app.UIAxes_T4, 'Z')
            app.UIAxes_T5 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T5, 'SEA Torque M5')
            xlabel(app.UIAxes_T5, 'Time [s]')
            ylabel(app.UIAxes_T5, '\tau [Nm]')
            zlabel(app.UIAxes_T5, 'Z')
            app.UIAxes_T6 = uiaxes(app.STARTUPTab);
            title(app.UIAxes_T6, 'SEA Torque M6')
            xlabel(app.UIAxes_T6, 'Time [s]')
            ylabel(app.UIAxes_T6, '\tau [Nm]')
            zlabel(app.UIAxes_T6, 'Z')
            app.ActualPosM1EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM1EditFieldLabel.Text = 'Actual Pos M1';
            app.ActualPosM1EditField = uieditfield(app.STARTUPTab, 'text');
            app.ActualPosM2EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM2EditFieldLabel.Text = 'Actual Pos M2';
            app.ActualPosM2EditField = uieditfield(app.STARTUPTab, 'text');
            app.ActualPosM3EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM3EditFieldLabel.Text = 'Actual Pos M3';
            app.ActualPosM3EditField = uieditfield(app.STARTUPTab, 'text');
            app.ActualPosM4EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM4EditFieldLabel.Text = 'Actual Pos M4';
            app.ActualPosM4EditField = uieditfield(app.STARTUPTab, 'text');
            app.ActualPosM5EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM5EditFieldLabel.Text = 'Actual Pos M5';
            app.ActualPosM5EditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM1INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM1INEditFieldLabel.Text = 'Aksim Pos M1 IN';
            app.AksimPosM1INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM2INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM2INEditFieldLabel.Text = 'Aksim Pos M2 IN';
            app.AksimPosM2INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM3INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM3INEditFieldLabel.Text = 'Aksim Pos M3 IN';
            app.AksimPosM3INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM4INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM4INEditFieldLabel.Text = 'Aksim Pos M4 IN';
            app.AksimPosM4INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM5INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM5INEditFieldLabel.Text = 'Aksim Pos M5 IN';
            app.AksimPosM5INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM1OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM1OUTEditFieldLabel.Text = 'Aksim Pos M1 OUT';
            app.AksimPosM1OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM2OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM2OUTEditFieldLabel.Text = 'Aksim Pos M2 OUT';
            app.AksimPosM2OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM3OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM3OUTEditFieldLabel.Text = 'Aksim Pos M3 OUT';
            app.AksimPosM3OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM4OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM4OUTEditFieldLabel.Text = 'Aksim Pos M4 OUT';
            app.AksimPosM4OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM5OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM5OUTEditFieldLabel.Text = 'Aksim Pos M5 OUT';
            app.AksimPosM5OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.WakeUpFLOATEvoSwitchLabel = uilabel(app.STARTUPTab);
            app.WakeUpFLOATEvoSwitchLabel.Text = 'WakeUpFLOATEvo';
            app.WakeUpFLOATEvoSwitch = uiswitch(app.STARTUPTab, 'toggle');
            app.WakeUpFLOATEvoSwitch.ValueChangedFcn = createCallbackFcn(app, @WakeUpFLOATEvoSwitchValueChanged, true);
            app.J1_HomingLampLabel = uilabel(app.STARTUPTab);
            app.J1_HomingLampLabel.Text = 'J1_Homing';
            app.J1_HomingLamp = uilamp(app.STARTUPTab);
            app.J2_HomingLampLabel = uilabel(app.STARTUPTab);
            app.J2_HomingLampLabel.Text = 'J2_Homing';
            app.J2_HomingLamp = uilamp(app.STARTUPTab);
            app.J3_HomingLampLabel = uilabel(app.STARTUPTab);
            app.J3_HomingLampLabel.Text = {'J3_Homing'; ''};
            app.J3_HomingLamp = uilamp(app.STARTUPTab);
            app.J4_HomingLampLabel = uilabel(app.STARTUPTab);
            app.J4_HomingLampLabel.Text = {'J4_Homing'; ''};
            app.J4_HomingLamp = uilamp(app.STARTUPTab);
            app.J5_HomingLampLabel = uilabel(app.STARTUPTab);
            app.J5_HomingLampLabel.Text = {'J5_Homing'; ''};
            app.J5_HomingLamp = uilamp(app.STARTUPTab);
            app.ActualPosM6EditFieldLabel = uilabel(app.STARTUPTab);
            app.ActualPosM6EditFieldLabel.Text = 'Actual Pos M6';
            app.ActualPosM6EditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM6INEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM6INEditFieldLabel.Text = 'Aksim Pos M6 IN';
            app.AksimPosM6INEditField = uieditfield(app.STARTUPTab, 'text');
            app.AksimPosM6OUTEditFieldLabel = uilabel(app.STARTUPTab);
            app.AksimPosM6OUTEditFieldLabel.Text = 'Aksim Pos M6 OUT';
            app.AksimPosM6OUTEditField = uieditfield(app.STARTUPTab, 'text');
            app.J5_HPLamp_2Label_2 = uilabel(app.STARTUPTab);
            app.J5_HPLamp_2Label_2.Text = {'J6_Homing'; ''};
            app.J6_HomingLamp = uilamp(app.STARTUPTab);
            app.HomingMonitorLabel = uilabel(app.STARTUPTab);
            app.HomingMonitorLabel.Text = 'Homing Monitor';
            app.TorqueMonitorLabel = uilabel(app.STARTUPTab);
            app.TorqueMonitorLabel.Text = 'Torque Monitor';
            app.WELCOMETab = uitab(app.TabGroup2);
            app.WELCOMETab.SizeChangedFcn = createCallbackFcn(app, @WELCOMETabSizeChanged, true);
            app.WELCOMETab.Title = 'WELCOME';
            app.ASSISTANCELEVELButtonGroup = uibuttongroup(app.WELCOMETab);
            app.ASSISTANCELEVELButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ASSISTANCELEVELButtonGroupSelectionChanged, true);
            app.ASSISTANCELEVELButtonGroup.Title = 'ASSISTANCE LEVEL';
            app.HighButton = uiradiobutton(app.ASSISTANCELEVELButtonGroup);
            app.HighButton.Text = 'High';
            app.MediumButton = uiradiobutton(app.ASSISTANCELEVELButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.LowButton_2 = uiradiobutton(app.ASSISTANCELEVELButtonGroup);
            app.LowButton_2.Text = 'Low';
            app.NoneButton = uiradiobutton(app.ASSISTANCELEVELButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.Image18 = uiimage(app.ASSISTANCELEVELButtonGroup);
            app.Image18.ImageSource = 'help.png';
            app.Image19 = uiimage(app.ASSISTANCELEVELButtonGroup);
            app.Image19.ImageSource = 'helpMe.png';
            app.Image4 = uiimage(app.WELCOMETab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'Homing.png';
            app.GoHomeLabel = uilabel(app.WELCOMETab);
            app.GoHomeLabel.Text = 'Go Home';
            app.WelcometoSphericalFloatAPPLabel = uilabel(app.WELCOMETab);
            app.WelcometoSphericalFloatAPPLabel.Text = 'Welcome to SphericalFloat APP!';
            app.Image7_2 = uiimage(app.WELCOMETab);
            app.Image7_2.ImageSource = '660527.png';
            app.JOINTANGLELabel = uilabel(app.WELCOMETab);
            app.JOINTANGLELabel.Text = 'JOINT ANGLE';
            app.Image8 = uiimage(app.WELCOMETab);
            app.Image8.ImageSource = 'ang.png';
            app.Image11 = uiimage(app.WELCOMETab);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @Image11Clicked, true);
            app.Image11.ImageSource = 'trasparenza.png';
            app.TransparencyLabel = uilabel(app.WELCOMETab);
            app.TransparencyLabel.Text = 'Transparency';
            app.CompliantexercisesLabel = uilabel(app.WELCOMETab);
            app.CompliantexercisesLabel.Text = 'Compliant exercises';
            app.Image7_3 = uiimage(app.WELCOMETab);
            app.Image7_3.ImageSource = '660527.png';
            app.RPYANGLESLabel = uilabel(app.WELCOMETab);
            app.RPYANGLESLabel.Text = 'RPY ANGLES';
            app.Image8_2 = uiimage(app.WELCOMETab);
            app.Image8_2.ImageSource = 'ang.png';
            app.StatusImage = uiimage(app.WELCOMETab);
            app.StatusImage.ImageSource = 'sleep_mode.png';
            app.StatusLabel = uilabel(app.WELCOMETab);
            app.StatusLabel.Text = 'Status';
            app.Image_3 = uiimage(app.WELCOMETab);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'Flexion_Disactive.svg';
            app.ShoulderFlexionExtensionLabel = uilabel(app.WELCOMETab);
            app.ShoulderFlexionExtensionLabel.Text = 'Shoulder Flexion-Extension';
            app.Image2_2 = uiimage(app.WELCOMETab);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2_2Clicked, true);
            app.Image2_2.ImageSource = 'Elbow_Flexion_Extension_Disactive.svg';
            app.ElbowFlexionExtensionLabel_2 = uilabel(app.WELCOMETab);
            app.ElbowFlexionExtensionLabel_2.Text = 'Elbow Flexion-Extension';
            app.Image12 = uiimage(app.WELCOMETab);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Image12Clicked, true);
            app.Image12.ImageSource = 'Abduction_Disactive.svg';
            app.ShoulderAbductionAdductionLabel = uilabel(app.WELCOMETab);
            app.ShoulderAbductionAdductionLabel.Text = 'Shoulder Abduction-Adduction';
            app.Image15 = uiimage(app.WELCOMETab);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Image15Clicked, true);
            app.Image15.ImageSource = 'record.png';
            app.LearnReplayLabel = uilabel(app.WELCOMETab);
            app.LearnReplayLabel.Text = 'Learn & Replay';
            app.Image16 = uiimage(app.WELCOMETab);
            app.Image16.ImageClickedFcn = createCallbackFcn(app, @Image16Clicked, true);
            app.Image16.ImageSource = 'replay.png';
            app.Image17 = uiimage(app.WELCOMETab);
            app.Image17.ImageClickedFcn = createCallbackFcn(app, @Image17Clicked, true);
            app.Image17.ImageSource = 'STOPREC.png';
            app.ShoulderFlexionEditFieldLabel = uilabel(app.WELCOMETab);
            app.ShoulderFlexionEditFieldLabel.Text = 'ShoulderFlexion';
            app.ShoulderFlexionEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ShoulderFlexionEditField.ValueDisplayFormat = '%.1f';
            app.ShoulderRorationEditFieldLabel = uilabel(app.WELCOMETab);
            app.ShoulderRorationEditFieldLabel.Text = 'ShoulderRoration';
            app.ShoulderRorationEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ShoulderRorationEditField.ValueDisplayFormat = '%.1f';
            app.ScapularProtractionEditFieldLabel = uilabel(app.WELCOMETab);
            app.ScapularProtractionEditFieldLabel.Text = 'ScapularProtraction';
            app.ScapularProtractionEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ScapularProtractionEditField.ValueDisplayFormat = '%.1f';
            app.ShoulderAbductionEditFieldLabel = uilabel(app.WELCOMETab);
            app.ShoulderAbductionEditFieldLabel.Text = 'ShoulderAbduction';
            app.ShoulderAbductionEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ShoulderAbductionEditField.ValueDisplayFormat = '%.1f';
            app.ElbowFlexionEditFieldLabel = uilabel(app.WELCOMETab);
            app.ElbowFlexionEditFieldLabel.Text = 'ElbowFlexion';
            app.ElbowFlexionEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ElbowFlexionEditField.ValueDisplayFormat = '%.1f';
            app.ScapularElevationEditFieldLabel = uilabel(app.WELCOMETab);
            app.ScapularElevationEditFieldLabel.Text = 'ScapularElevation';
            app.ScapularElevationEditField = uieditfield(app.WELCOMETab, 'numeric');
            app.ScapularElevationEditField.ValueDisplayFormat = '%.1f';
            app.ROLLEditFieldLabel = uilabel(app.WELCOMETab);
            app.ROLLEditFieldLabel.Text = 'ROLL';
            app.ROLLEditField = uieditfield(app.WELCOMETab, 'text');
            app.PITCHEditFieldLabel = uilabel(app.WELCOMETab);
            app.PITCHEditFieldLabel.Text = 'PITCH';
            app.PITCHEditField = uieditfield(app.WELCOMETab, 'text');
            app.YAWEditFieldLabel = uilabel(app.WELCOMETab);
            app.YAWEditFieldLabel.Text = 'YAW';
            app.YAWEditField = uieditfield(app.WELCOMETab, 'text');
            app.RepetitionsSpinnerLabel = uilabel(app.WELCOMETab);
            app.RepetitionsSpinnerLabel.Text = 'Repetitions';
            app.RepetitionsSpinner = uispinner(app.WELCOMETab);
            app.RepetitionsSpinner.Limits = [1 10];
            app.RepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @RepetitionsSpinnerValueChanged, true);
            app.RepetitionsSpinner.Value = 1;
            app.TimingsecSpinnerLabel = uilabel(app.WELCOMETab);
            app.TimingsecSpinnerLabel.Text = 'Timing [sec]';
            app.TimingsecSpinner = uispinner(app.WELCOMETab);
            app.TimingsecSpinner.Limits = [1 20];
            app.TimingsecSpinner.ValueChangedFcn = createCallbackFcn(app, @TimingsecSpinnerValueChanged, true);
            app.TimingsecSpinner.Value = 1;
            app.TargetSpinnerLabel = uilabel(app.WELCOMETab);
            app.TargetSpinnerLabel.Text = 'Target';
            app.TargetSpinner = uispinner(app.WELCOMETab);
            app.TargetSpinner.Step = 30;
            app.TargetSpinner.Limits = [0 120];
            app.TargetSpinner.ValueChangedFcn = createCallbackFcn(app, @TargetSpinnerValueChanged, true);
            app.Image20 = uiimage(app.WELCOMETab);
            app.Image20.ImageSource = 'LOAD.png';
            app.MOVEMENTDIRECTIONLabel = uilabel(app.WELCOMETab);
            app.MOVEMENTDIRECTIONLabel.Text = 'MOVEMENT DIRECTION';
            app.UTILITYTab = uitab(app.TabGroup2);
            app.UTILITYTab.Title = 'UTILITY';
            app.Statusword_m1EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m1EditFieldLabel.Text = 'Statusword_m1';
            app.Statusword_m1EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m1EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m1EditFieldLabel.Text = 'Error code_m1';
            app.Errorcode_m1EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m1EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m1EditFieldLabel.Text = 'Read NMT state_m1';
            app.ReadNMTstate_m1EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm1EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm1EditFieldLabel.Text = 'Mode of Operation m1';
            app.ModeofOperationm1EditField = uieditfield(app.UTILITYTab, 'text');
            app.Statusword_m2EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m2EditFieldLabel.Text = 'Statusword_m2';
            app.Statusword_m2EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m2EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m2EditFieldLabel.Text = 'Read NMT state_m2';
            app.ReadNMTstate_m2EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m2EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m2EditFieldLabel.Text = 'Error code_m2';
            app.Errorcode_m2EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm2EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm2EditFieldLabel.Text = 'Mode of Operation m2';
            app.ModeofOperationm2EditField = uieditfield(app.UTILITYTab, 'text');
            app.Statusword_m3EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m3EditFieldLabel.Text = 'Statusword_m3';
            app.Statusword_m3EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m3EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m3EditFieldLabel.Text = 'Read NMT state_m3';
            app.ReadNMTstate_m3EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m3EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m3EditFieldLabel.Text = 'Error code_m3';
            app.Errorcode_m3EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm3EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm3EditFieldLabel.Text = 'Mode of Operation m3';
            app.ModeofOperationm3EditField = uieditfield(app.UTILITYTab, 'text');
            app.setModem1ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem1ButtonGroupSelectionChanged, true);
            app.setModem1ButtonGroup.Title = 'set Mode m1';
            app.ProfilePositionModePPMButton = uiradiobutton(app.setModem1ButtonGroup);
            app.ProfilePositionModePPMButton.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton.Value = true;
            app.ProfileVelocityModePVMButton = uiradiobutton(app.setModem1ButtonGroup);
            app.ProfileVelocityModePVMButton.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton = uiradiobutton(app.setModem1ButtonGroup);
            app.HomingModeHMMButton.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton = uiradiobutton(app.setModem1ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton = uiradiobutton(app.setModem1ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton = uiradiobutton(app.setModem1ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.setModem2ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem2ButtonGroupSelectionChanged, true);
            app.setModem2ButtonGroup.Title = 'set Mode m2';
            app.ProfilePositionModePPMButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.ProfilePositionModePPMButton_2.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton_2.Value = true;
            app.ProfileVelocityModePVMButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.ProfileVelocityModePVMButton_2.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.HomingModeHMMButton_2.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton_2.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton_2.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton_2 = uiradiobutton(app.setModem2ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton_2.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.setModem3ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem3ButtonGroupSelectionChanged, true);
            app.setModem3ButtonGroup.Title = 'set Mode m3';
            app.ProfilePositionModePPMButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.ProfilePositionModePPMButton_3.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton_3.Value = true;
            app.ProfileVelocityModePVMButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.ProfileVelocityModePVMButton_3.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.HomingModeHMMButton_3.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton_3.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton_3.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton_3 = uiradiobutton(app.setModem3ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton_3.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.controlwordm1ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm1ButtonGroupSelectionChanged, true);
            app.controlwordm1ButtonGroup.Title = 'controlword m1';
            app.ShutdownButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.ShutdownButton.Text = 'Shutdown';
            app.ShutdownButton.Value = true;
            app.SwitchOnButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.SwitchOnButton.Text = 'Switch On';
            app.SwitchOnEnableOperationButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.SwitchOnEnableOperationButton.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.DisableVoltageButton.Text = 'Disable Voltage';
            app.QuickstopButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.QuickstopButton.Text = 'Quick stop';
            app.DisableOperationButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.DisableOperationButton.Text = 'Disable Operation';
            app.EnableOperationButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.EnableOperationButton.Text = 'Enable Operation';
            app.FaultresetButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.FaultresetButton.Text = 'Fault reset';
            app.HaltButton = uiradiobutton(app.controlwordm1ButtonGroup);
            app.HaltButton.Text = 'Halt';
            app.controlwordm2ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm2ButtonGroupSelectionChanged, true);
            app.controlwordm2ButtonGroup.Title = 'controlword m2';
            app.ShutdownButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.ShutdownButton_2.Text = 'Shutdown';
            app.ShutdownButton_2.Value = true;
            app.SwitchOnButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.SwitchOnButton_2.Text = 'Switch On';
            app.SwitchOnEnableOperationButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.SwitchOnEnableOperationButton_2.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.DisableVoltageButton_2.Text = 'Disable Voltage';
            app.QuickstopButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.QuickstopButton_2.Text = 'Quick stop';
            app.DisableOperationButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.DisableOperationButton_2.Text = 'Disable Operation';
            app.EnableOperationButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.EnableOperationButton_2.Text = 'Enable Operation';
            app.FaultresetButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.FaultresetButton_2.Text = 'Fault reset';
            app.HaltButton_2 = uiradiobutton(app.controlwordm2ButtonGroup);
            app.HaltButton_2.Text = 'Halt';
            app.controlwordm3ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm3ButtonGroupSelectionChanged, true);
            app.controlwordm3ButtonGroup.Title = 'controlword m3';
            app.ShutdownButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.ShutdownButton_3.Text = 'Shutdown';
            app.ShutdownButton_3.Value = true;
            app.SwitchOnButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.SwitchOnButton_3.Text = 'Switch On';
            app.SwitchOnEnableOperationButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.SwitchOnEnableOperationButton_3.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.DisableVoltageButton_3.Text = 'Disable Voltage';
            app.QuickstopButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.QuickstopButton_3.Text = 'Quick stop';
            app.DisableOperationButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.DisableOperationButton_3.Text = 'Disable Operation';
            app.EnableOperationButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.EnableOperationButton_3.Text = 'Enable Operation';
            app.FaultresetButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.FaultresetButton_3.Text = 'Fault reset';
            app.HaltButton_3 = uiradiobutton(app.controlwordm3ButtonGroup);
            app.HaltButton_3.Text = 'Halt';
            app.controlwordm4ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm4ButtonGroupSelectionChanged, true);
            app.controlwordm4ButtonGroup.Title = 'controlword m4';
            app.ShutdownButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.ShutdownButton_4.Text = 'Shutdown';
            app.ShutdownButton_4.Value = true;
            app.SwitchOnButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.SwitchOnButton_4.Text = 'Switch On';
            app.SwitchOnEnableOperationButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.SwitchOnEnableOperationButton_4.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.DisableVoltageButton_4.Text = 'Disable Voltage';
            app.QuickstopButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.QuickstopButton_4.Text = 'Quick stop';
            app.DisableOperationButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.DisableOperationButton_4.Text = 'Disable Operation';
            app.EnableOperationButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.EnableOperationButton_4.Text = 'Enable Operation';
            app.FaultresetButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.FaultresetButton_4.Text = 'Fault reset';
            app.HaltButton_4 = uiradiobutton(app.controlwordm4ButtonGroup);
            app.HaltButton_4.Text = 'Halt';
            app.controlwordm5ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm5ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm5ButtonGroupSelectionChanged, true);
            app.controlwordm5ButtonGroup.Title = 'controlword m5';
            app.ShutdownButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.ShutdownButton_5.Text = 'Shutdown';
            app.ShutdownButton_5.Value = true;
            app.SwitchOnButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.SwitchOnButton_5.Text = 'Switch On';
            app.SwitchOnEnableOperationButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.SwitchOnEnableOperationButton_5.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.DisableVoltageButton_5.Text = 'Disable Voltage';
            app.QuickstopButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.QuickstopButton_5.Text = 'Quick stop';
            app.DisableOperationButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.DisableOperationButton_5.Text = 'Disable Operation';
            app.EnableOperationButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.EnableOperationButton_5.Text = 'Enable Operation';
            app.FaultresetButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.FaultresetButton_5.Text = 'Fault reset';
            app.HaltButton_5 = uiradiobutton(app.controlwordm5ButtonGroup);
            app.HaltButton_5.Text = 'Halt';
            app.setModem4ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem4ButtonGroupSelectionChanged, true);
            app.setModem4ButtonGroup.Title = 'set Mode m4';
            app.ProfilePositionModePPMButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.ProfilePositionModePPMButton_4.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton_4.Value = true;
            app.ProfileVelocityModePVMButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.ProfileVelocityModePVMButton_4.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.HomingModeHMMButton_4.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton_4.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton_4.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton_4 = uiradiobutton(app.setModem4ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton_4.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.setModem5ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem5ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem5ButtonGroupSelectionChanged, true);
            app.setModem5ButtonGroup.Title = 'set Mode m5';
            app.ProfilePositionModePPMButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.ProfilePositionModePPMButton_5.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton_5.Value = true;
            app.ProfileVelocityModePVMButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.ProfileVelocityModePVMButton_5.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.HomingModeHMMButton_5.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton_5.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton_5.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton_5 = uiradiobutton(app.setModem5ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton_5.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.Statusword_m4EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m4EditFieldLabel.Text = 'Statusword_m4';
            app.Statusword_m4EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m4EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m4EditFieldLabel.Text = 'Read NMT state_m4';
            app.ReadNMTstate_m4EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m4EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m4EditFieldLabel.Text = 'Error code_m4';
            app.Errorcode_m4EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm4EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm4EditFieldLabel.Text = 'Mode of Operation m4';
            app.ModeofOperationm4EditField = uieditfield(app.UTILITYTab, 'text');
            app.Statusword_m5EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m5EditFieldLabel.Text = 'Statusword_m5';
            app.Statusword_m5EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m5EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m5EditFieldLabel.Text = 'Read NMT state_m5';
            app.ReadNMTstate_m5EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m5EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m5EditFieldLabel.Text = 'Error code_m5';
            app.Errorcode_m5EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm5EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm5EditFieldLabel.Text = 'Mode of Operation m5';
            app.ModeofOperationm5EditField = uieditfield(app.UTILITYTab, 'text');
            app.setNMTstatebothmotorsButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setNMTstatebothmotorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setNMTstatebothmotorsButtonGroupSelectionChanged, true);
            app.setNMTstatebothmotorsButtonGroup.Title = 'set NMTstate both motors';
            app.EnterPreOperationalButton = uiradiobutton(app.setNMTstatebothmotorsButtonGroup);
            app.EnterPreOperationalButton.Text = 'Enter Pre-Operational';
            app.EnterPreOperationalButton.Value = true;
            app.ResetCommunicationButton = uiradiobutton(app.setNMTstatebothmotorsButtonGroup);
            app.ResetCommunicationButton.Text = 'Reset Communication';
            app.ResetNodeButton = uiradiobutton(app.setNMTstatebothmotorsButtonGroup);
            app.ResetNodeButton.Text = 'Reset Node';
            app.StartRemoteNodeButton = uiradiobutton(app.setNMTstatebothmotorsButtonGroup);
            app.StartRemoteNodeButton.Text = 'Start Remote Node';
            app.StopRemoteNodeButton = uiradiobutton(app.setNMTstatebothmotorsButtonGroup);
            app.StopRemoteNodeButton.Text = 'Stop Remote Node';
            app.controlwordm6ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.controlwordm6ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @controlwordm6ButtonGroupSelectionChanged, true);
            app.controlwordm6ButtonGroup.Title = 'controlword m6';
            app.ShutdownButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.ShutdownButton_6.Text = 'Shutdown';
            app.ShutdownButton_6.Value = true;
            app.SwitchOnButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.SwitchOnButton_6.Text = 'Switch On';
            app.SwitchOnEnableOperationButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.SwitchOnEnableOperationButton_6.Text = 'Switch On & Enable Operation';
            app.DisableVoltageButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.DisableVoltageButton_6.Text = 'Disable Voltage';
            app.QuickstopButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.QuickstopButton_6.Text = 'Quick stop';
            app.DisableOperationButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.DisableOperationButton_6.Text = 'Disable Operation';
            app.EnableOperationButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.EnableOperationButton_6.Text = 'Enable Operation';
            app.FaultresetButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.FaultresetButton_6.Text = 'Fault reset';
            app.HaltButton_6 = uiradiobutton(app.controlwordm6ButtonGroup);
            app.HaltButton_6.Text = 'Halt';
            app.setModem6ButtonGroup = uibuttongroup(app.UTILITYTab);
            app.setModem6ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @setModem6ButtonGroupSelectionChanged, true);
            app.setModem6ButtonGroup.Title = 'set Mode m6';
            app.ProfilePositionModePPMButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.ProfilePositionModePPMButton_6.Text = 'Profile Position Mode (PPM)';
            app.ProfilePositionModePPMButton_6.Value = true;
            app.ProfileVelocityModePVMButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.ProfileVelocityModePVMButton_6.Text = 'Profile Velocity Mode (PVM)';
            app.HomingModeHMMButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.HomingModeHMMButton_6.Text = 'Homing Mode (HMM)';
            app.CyclicSynchronousPositionModeCSPButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.CyclicSynchronousPositionModeCSPButton_6.Text = 'Cyclic Synchronous Position Mode (CSP)';
            app.CyclicSynchronousVelocityModeCSVButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.CyclicSynchronousVelocityModeCSVButton_6.Text = 'Cyclic Synchronous Velocity Mode (CSV)';
            app.CyclicSynchronousTorqueModeCSTButton_6 = uiradiobutton(app.setModem6ButtonGroup);
            app.CyclicSynchronousTorqueModeCSTButton_6.Text = 'Cyclic Synchronous Torque Mode (CST)';
            app.Statusword_m6EditFieldLabel = uilabel(app.UTILITYTab);
            app.Statusword_m6EditFieldLabel.Text = 'Statusword_m6';
            app.Statusword_m6EditField = uieditfield(app.UTILITYTab, 'text');
            app.ReadNMTstate_m6EditFieldLabel = uilabel(app.UTILITYTab);
            app.ReadNMTstate_m6EditFieldLabel.Text = 'Read NMT state_m6';
            app.ReadNMTstate_m6EditField = uieditfield(app.UTILITYTab, 'text');
            app.Errorcode_m6EditFieldLabel = uilabel(app.UTILITYTab);
            app.Errorcode_m6EditFieldLabel.Text = 'Error code_m6';
            app.Errorcode_m6EditField = uieditfield(app.UTILITYTab, 'text');
            app.ModeofOperationm6EditFieldLabel = uilabel(app.UTILITYTab);
            app.ModeofOperationm6EditFieldLabel.Text = 'Mode of Operation m6';
            app.ModeofOperationm6EditField = uieditfield(app.UTILITYTab, 'text');
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.UIFigure);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.UIFigure);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.UIFigure);
            app.SystemLog = slrealtime.ui.control.SystemLog(app.UIFigure);
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.UIFigure);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.UIFigure);
            app.Image13 = uiimage(app.UIFigure);
            app.Image13.ImageSource = 'layout_set_logo.png';
            app.Image14 = uiimage(app.UIFigure);
            app.Image14.ImageSource = 'LogoInail.svg';
            app.SphericalFloatExoskeletonLabel = uilabel(app.UIFigure);
            app.SphericalFloatExoskeletonLabel.Text = 'SphericalFloat Exoskeleton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicUserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end